classdef simulation_veritical_stripe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationofVerticalStripeROIImagingUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SvaeMatrixButton                matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        TestPanel                       matlab.ui.container.Panel
        ReverseEvenRowsCheckBox         matlab.ui.control.CheckBox
        ScanBackRight2pixelSpinner      matlab.ui.control.Spinner
        ScanBackRightpixelLabel         matlab.ui.control.Label
        ScanBackLeft2pixelSpinner       matlab.ui.control.Spinner
        ScanBackLeftpixelLabel          matlab.ui.control.Label
        PulsePerPixelSpinner            matlab.ui.control.Spinner
        PulsePerPixelSpinnerLabel       matlab.ui.control.Label
        ImageSizeDropDown               matlab.ui.control.DropDown
        ImageSizeDropDownLabel          matlab.ui.control.Label
        WaitpulseSpinner                matlab.ui.control.Spinner
        WaitpulseSpinnerLabel           matlab.ui.control.Label
        ActualParamterPanel             matlab.ui.container.Panel
        ActualScanFlipCheckBox          matlab.ui.control.CheckBox
        ActualScanBackRightpixelSpinner  matlab.ui.control.Spinner
        ScanBackRight2pixelLabel        matlab.ui.control.Label
        ActualScanBackLeftpixelSpinner  matlab.ui.control.Spinner
        ScanBackLeft2pixelLabel         matlab.ui.control.Label
        ActualWaitpulseSpinner          matlab.ui.control.Spinner
        WaitpulseSpinner_2Label         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pulseOff = 1;% 代表激光关
        pulseOn = 0; % 代表激光开
        imageSize;
        frameImage;
        roiMask % Description
    end
    
    methods (Access = private)
        
        function main(app)
            get_image_size(app);
            framePulse= create_frame_pulse(app);
            app.frameImage = create_frame_image(app,framePulse);
            plot_figure(app,app.frameImage)

        end
        function plot_figure(app,frameImage)

            imagesc(app.UIAxes,frameImage); 
            drawnow;
            colormap(app.UIAxes,gray());
            app.UIAxes.XLim = [0,app.imageSize];
            app.UIAxes.YLim = [0,app.imageSize];
            set(app.UIAxes_2,'DataAspectRatio',[0.8980099502487562,0.8980099502487562,1])


        end
        
        function get_image_size(app)
            value = app.ImageSizeDropDown.Value;
            switch value
                case '512*512'
                    app.imageSize = 512;
                case '256*256'
                    app.imageSize = 256;
            end
        end

        function create_roi_mask(app)
            
            vectors = [repmat(app.pulseOn,1,32) repmat(app.pulseOff,1,32)]; % 白黑条纹是32个1和32个0，
            binary_im_1d = repmat(vectors,1,app.imageSize*app.imageSize/64); % 组成的图像
            app.roiMask  = ~reshape(binary_im_1d,app.imageSize,app.imageSize)';
        end

        function framePulse= create_frame_pulse(app)
            % 为每一帧生成调制的脉冲
            waitTry = app.WaitpulseSpinner.Value; % unit: pulse
            scanBackLeftPixelDouble = app.ScanBackLeft2pixelSpinner.Value; % unit: pixel
            scanBackRightPixelDouble = app.ScanBackRight2pixelSpinner.Value; % unit: pixel
            pulsePerPixel = app.PulsePerPixelSpinner.Value;
            

            % Generate ROI mask
            roiMaskPulse = repelem(~app.roiMask, 1, pulsePerPixel); % Each pixel has several pulses, so the signal needs to be repeated
            
            
            % 是否将偶数行信号翻转
            if app.ReverseEvenRowsCheckBox.Value

                  roiMaskPulse  = flip_matrix(app,roiMaskPulse);
                  %roiMaskPulse(2:2:end, :) = fliplr(roiMaskPulse(2:2:end, :)); % Because of bidirectional scanning, the laser is scanned from left to right in the first line, and the second line is directly scanned from right, so even rows need to be mirrored
            end

            
            % Add scan right to odd rows
            oddLineSignal = [roiMaskPulse(1:2:end, :) repmat(app.pulseOff, app.imageSize / 2, scanBackRightPixelDouble)];
            
            % Add scan left to even rows
            evenLineSignal = [roiMaskPulse(2:2:end, :) repmat(app.pulseOff, app.imageSize / 2, scanBackLeftPixelDouble)];
            
            % Then the odd row matrix and the even row matrix are pasted together in sequence and reduced to 1*n (considering the impact of changing the length of the array dynamically in the loop on performance, so use this method to optimize)
            signalPulse = reshape([oddLineSignal evenLineSignal]', 1, []);
            
            % Compose the final framePusle
            waitPulse = repmat(app.pulseOff, 1, waitTry); % Add wait time in front
            scanleftFirstPulse = repmat(app.pulseOff, 1, round(scanBackLeftPixelDouble / 2));
            framePulse = [waitPulse, scanleftFirstPulse, signalPulse(1:end - round(scanBackRightPixelDouble / 2))]; % The first scanleft is missing, the last scanright is extra
        end
        
        function [frameImage,imageWidth,leftPaddingActual,rightPaddingActual]= create_frame_image(app,framePulse)
            waitActual = app.ActualWaitpulseSpinner.Value;
            leftPaddingActual = app.ActualScanBackLeftpixelSpinner.Value;
            rightPaddingActual = app.ActualScanBackRightpixelSpinner.Value;
            

            imageWidth = app.imageSize+(leftPaddingActual+rightPaddingActual)/2;
            
            
            % 考虑waitActual
            framePixel = framePulse(waitActual+1:end); % 用于实际成像的pixel信号
            
            % 减去第一个scan left
            framePixel = framePixel(round(leftPaddingActual/2)+1:end);

            % 加上最后一个scanright
            framePixel = [framePixel repmat(app.pulseOff, 1, round(rightPaddingActual / 2))];
            
            % 计算pixel 是否足够，不足补pulseOff,太多则砍掉
            needPixels = (2*app.imageSize+leftPaddingActual+rightPaddingActual)*app.imageSize/2;
            L = length(framePixel);
            if L < needPixels
                framePixel(L+1:needPixels) = app.pulseOff;
            else
                framePixel = framePixel(1:needPixels);
            end

            
            % 复原信号
            frameImage = reshape(framePixel,2*app.imageSize+leftPaddingActual+rightPaddingActual,app.imageSize/2)';
            oddLineSignal = frameImage(:,1:(app.imageSize+rightPaddingActual));
            evenLineSignal = frameImage(:,(app.imageSize+rightPaddingActual+1):end);
            oddRoiSignal = oddLineSignal(:,1:app.imageSize);
            evenRoiSignal = evenLineSignal(:,1:app.imageSize);
            
            frameImage = ones(app.imageSize,app.imageSize);
            frameImage(1:2:end,:) = oddRoiSignal;
            frameImage(2:2:end,:) = evenRoiSignal;

            % 因为scanner会对像素进行翻转
            if app.ActualScanFlipCheckBox.Value
                frameImage  = flip_matrix(app,frameImage);
            end
            
            frameImage = ~frameImage; % 鉴于激光是active low logic，所以取反
            
        end

        function result = flip_matrix(~,matrix)
            for i = 1:size(matrix, 1)
                if mod(i, 2) == 0 % 判断是否是偶数行
                    matrix(i, :) = fliplr(matrix(i, :)); % 利用 fliplr 函数将该行左右翻转
                end
            end
            result = matrix;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            get_image_size(app);
            create_roi_mask(app);

            imagesc(app.UIAxes_2,app.roiMask); 
            colormap(app.UIAxes_2,gray()); % 1代表黑，0代表白
            app.UIAxes_2.XLim = [0,app.imageSize];
            app.UIAxes_2.YLim = [0,app.imageSize];
            set(app.UIAxes_2,'DataAspectRatio',[0.8980099502487562,0.8980099502487562,1])
            main(app);

            hold(app.UIAxes,'off');

        end

        % Value changing function: PulsePerPixelSpinner
        function PulsePerPixelSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changing function: WaitpulseSpinner
        function WaitpulseSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changing function: ScanBackLeft2pixelSpinner
        function ScanBackLeft2pixelSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changing function: ScanBackRight2pixelSpinner
        function ScanBackRight2pixelSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changed function: ReverseEvenRowsCheckBox
        function ReverseEvenRowsCheckBoxValueChanged(app, event)
            main(app);
        end

        % Value changing function: ActualWaitpulseSpinner
        function ActualWaitpulseSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changing function: ActualScanBackLeftpixelSpinner
        function ActualScanBackLeftpixelSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changing function: ActualScanBackRightpixelSpinner
        function ActualScanBackRightpixelSpinnerValueChanging(app, event)
            main(app);
        end

        % Value changed function: ImageSizeDropDown
        function ImageSizeDropDownValueChanged(app, event)
            main(app);
        end

        % Value changed function: PulsePerPixelSpinner
        function PulsePerPixelSpinnerValueChanged(app, event)
            main(app);
        end

        % Value changed function: WaitpulseSpinner
        function WaitpulseSpinnerValueChanged(app, event)
            main(app);
        end

        % Value changed function: ScanBackLeft2pixelSpinner
        function ScanBackLeft2pixelSpinnerValueChanged(app, event)
            % app.ScanBackRight2pixelSpinner.Value = app.ScanBackLeft2pixelSpinner.Value;
            main(app);
        end

        % Value changed function: ScanBackRight2pixelSpinner
        function ScanBackRight2pixelSpinnerValueChanged(app, event)
            main(app);
        end

        % Value changed function: ActualWaitpulseSpinner
        function ActualWaitpulseSpinnerValueChanged(app, event)
            main(app);
        end

        % Value changed function: ActualScanBackLeftpixelSpinner
        function ActualScanBackLeftpixelSpinnerValueChanged(app, event)
            main(app);
        end

        % Value changed function: ActualScanBackRightpixelSpinner
        function ActualScanBackRightpixelSpinnerValueChanged(app, event)
            main(app);
        end

        % Value changed function: ActualScanFlipCheckBox
        function ActualScanFlipCheckBoxValueChanged(app, event)
            main(app);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.jpg';'*.png';'tif'}, 'Save as');
            
            if ischar(filename) && ischar(pathname)
                fullpath = fullfile(pathname, filename);
                imwrite(app.frameImage, fullpath);
            end
        end

        % Button pushed function: SvaeMatrixButton
        function SvaeMatrixButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.csv'}, 'Save as');
            
            if ischar(filename) && ischar(pathname)
                fullpath = fullfile(pathname, filename);
                writematrix(app.frameImage, fullpath);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SimulationofVerticalStripeROIImagingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {314, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationofVerticalStripeROIImagingUIFigure = uifigure('Visible', 'off');
            app.SimulationofVerticalStripeROIImagingUIFigure.Name = 'Simulation of Vertical Stripe ROI Imaging';
            app.SimulationofVerticalStripeROIImagingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SimulationofVerticalStripeROIImagingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ActualParamterPanel = uipanel(app.LeftPanel);
            app.ActualParamterPanel.Title = 'Actual Paramter';
            app.WaitpulseSpinner_2Label = uilabel(app.ActualParamterPanel);
            app.WaitpulseSpinner_2Label.Text = 'Wait (pulse)';
            app.ActualWaitpulseSpinner = uispinner(app.ActualParamterPanel);
            app.ActualWaitpulseSpinner.ValueChangingFcn = createCallbackFcn(app, @ActualWaitpulseSpinnerValueChanging, true);
            app.ActualWaitpulseSpinner.Limits = [0 Inf];
            app.ActualWaitpulseSpinner.ValueDisplayFormat = '%.0f';
            app.ActualWaitpulseSpinner.ValueChangedFcn = createCallbackFcn(app, @ActualWaitpulseSpinnerValueChanged, true);
            app.ActualWaitpulseSpinner.Value = 56;
            app.ScanBackLeft2pixelLabel = uilabel(app.ActualParamterPanel);
            app.ScanBackLeft2pixelLabel.Text = 'Scan Back Left*2 (pixel)';
            app.ActualScanBackLeftpixelSpinner = uispinner(app.ActualParamterPanel);
            app.ActualScanBackLeftpixelSpinner.ValueChangingFcn = createCallbackFcn(app, @ActualScanBackLeftpixelSpinnerValueChanging, true);
            app.ActualScanBackLeftpixelSpinner.Limits = [0 Inf];
            app.ActualScanBackLeftpixelSpinner.ValueChangedFcn = createCallbackFcn(app, @ActualScanBackLeftpixelSpinnerValueChanged, true);
            app.ActualScanBackLeftpixelSpinner.Value = 55;
            app.ScanBackRight2pixelLabel = uilabel(app.ActualParamterPanel);
            app.ScanBackRight2pixelLabel.Text = 'Scan Back Right*2 (pixel)';
            app.ActualScanBackRightpixelSpinner = uispinner(app.ActualParamterPanel);
            app.ActualScanBackRightpixelSpinner.ValueChangingFcn = createCallbackFcn(app, @ActualScanBackRightpixelSpinnerValueChanging, true);
            app.ActualScanBackRightpixelSpinner.Limits = [0 Inf];
            app.ActualScanBackRightpixelSpinner.ValueDisplayFormat = '%.0f';
            app.ActualScanBackRightpixelSpinner.ValueChangedFcn = createCallbackFcn(app, @ActualScanBackRightpixelSpinnerValueChanged, true);
            app.ActualScanBackRightpixelSpinner.Value = 55;
            app.ActualScanFlipCheckBox = uicheckbox(app.ActualParamterPanel);
            app.ActualScanFlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActualScanFlipCheckBoxValueChanged, true);
            app.ActualScanFlipCheckBox.Text = 'Bidirectional Scan';
            app.ActualScanFlipCheckBox.Value = true;
            app.TestPanel = uipanel(app.LeftPanel);
            app.TestPanel.Title = 'Test';
            app.WaitpulseSpinnerLabel = uilabel(app.TestPanel);
            app.WaitpulseSpinnerLabel.Text = 'Wait (pulse)';
            app.WaitpulseSpinner = uispinner(app.TestPanel);
            app.WaitpulseSpinner.ValueChangingFcn = createCallbackFcn(app, @WaitpulseSpinnerValueChanging, true);
            app.WaitpulseSpinner.Limits = [0 Inf];
            app.WaitpulseSpinner.ValueChangedFcn = createCallbackFcn(app, @WaitpulseSpinnerValueChanged, true);
            app.WaitpulseSpinner.Value = 56;
            app.ImageSizeDropDownLabel = uilabel(app.TestPanel);
            app.ImageSizeDropDownLabel.Text = 'Image Size';
            app.ImageSizeDropDown = uidropdown(app.TestPanel);
            app.ImageSizeDropDown.Items = {'512*512', '256*256'};
            app.ImageSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSizeDropDownValueChanged, true);
            app.ImageSizeDropDown.Value = '512*512';
            app.PulsePerPixelSpinnerLabel = uilabel(app.TestPanel);
            app.PulsePerPixelSpinnerLabel.Text = 'Pulse Per Pixel';
            app.PulsePerPixelSpinner = uispinner(app.TestPanel);
            app.PulsePerPixelSpinner.ValueChangingFcn = createCallbackFcn(app, @PulsePerPixelSpinnerValueChanging, true);
            app.PulsePerPixelSpinner.Limits = [1 2];
            app.PulsePerPixelSpinner.ValueDisplayFormat = '%.0f';
            app.PulsePerPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PulsePerPixelSpinnerValueChanged, true);
            app.PulsePerPixelSpinner.Editable = 'off';
            app.PulsePerPixelSpinner.Enable = 'off';
            app.PulsePerPixelSpinner.Value = 1;
            app.ScanBackLeftpixelLabel = uilabel(app.TestPanel);
            app.ScanBackLeftpixelLabel.Text = 'Scan Back Left*2 (pixel)';
            app.ScanBackLeft2pixelSpinner = uispinner(app.TestPanel);
            app.ScanBackLeft2pixelSpinner.ValueChangingFcn = createCallbackFcn(app, @ScanBackLeft2pixelSpinnerValueChanging, true);
            app.ScanBackLeft2pixelSpinner.Limits = [0 Inf];
            app.ScanBackLeft2pixelSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanBackLeft2pixelSpinnerValueChanged, true);
            app.ScanBackLeft2pixelSpinner.Value = 55;
            app.ScanBackRightpixelLabel = uilabel(app.TestPanel);
            app.ScanBackRightpixelLabel.Text = 'Scan Back Right*2 (pixel)';
            app.ScanBackRight2pixelSpinner = uispinner(app.TestPanel);
            app.ScanBackRight2pixelSpinner.ValueChangingFcn = createCallbackFcn(app, @ScanBackRight2pixelSpinnerValueChanging, true);
            app.ScanBackRight2pixelSpinner.Limits = [0 Inf];
            app.ScanBackRight2pixelSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanBackRight2pixelSpinnerValueChanged, true);
            app.ScanBackRight2pixelSpinner.Value = 55;
            app.ReverseEvenRowsCheckBox = uicheckbox(app.TestPanel);
            app.ReverseEvenRowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseEvenRowsCheckBoxValueChanged, true);
            app.ReverseEvenRowsCheckBox.Text = 'Reverse Even Rows';
            app.ReverseEvenRowsCheckBox.Value = true;
            app.SaveImageButton = uibutton(app.LeftPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.SvaeMatrixButton = uibutton(app.LeftPanel, 'push');
            app.SvaeMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SvaeMatrixButtonPushed, true);
            app.SvaeMatrixButton.Text = 'Svae Matrix';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Simulation')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_2, 'Z')
            subtitle(app.UIAxes_2, 'Input ROI Mask')
            % Show the figure after all components are created
            app.SimulationofVerticalStripeROIImagingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_veritical_stripe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationofVerticalStripeROIImagingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationofVerticalStripeROIImagingUIFigure)
        end
    end
end