classdef AwgSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AWGSettingsUIFigure           matlab.ui.Figure
        ExportSignalDropDown          matlab.ui.control.DropDown
        ExportSignalDropDownLabel     matlab.ui.control.Label
        DelaynsEditField              matlab.ui.control.NumericEditField
        DelaynsEditFieldLabel         matlab.ui.control.Label
        ExternalTriggerDropDown       matlab.ui.control.DropDown
        ExternalTriggerDropDownLabel  matlab.ui.control.Label
        SignalTypeDropDown            matlab.ui.control.DropDown
        ExportSignalPort              matlab.ui.control.EditField
        AwgTriggerPort                matlab.ui.control.EditField
        AwgExternalClockPort          matlab.ui.control.EditField
        SampleClockDropDown           matlab.ui.control.DropDown
        SampleClockLabel              matlab.ui.control.Label
        ArbOffsetEditField            matlab.ui.control.NumericEditField
        ArbOffsetEditFieldLabel       matlab.ui.control.Label
        ArbGainEditField              matlab.ui.control.NumericEditField
        GainEditFieldLabel            matlab.ui.control.Label
        SampleRateEditField           matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel      matlab.ui.control.Label
        ArbModeDropDown               matlab.ui.control.DropDown
        TriggerModeLabel              matlab.ui.control.Label
        DeviceIDEditField             matlab.ui.control.EditField
        DeviceIDEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp; % Main App
    end
    
    methods (Access = public)
        
        function variableInit(app)
            resourceID = app.MainApp.waveformConfig.resourceID;
            gain = app.MainApp.waveformConfig.gain;
            offset = app.MainApp.waveformConfig.offset;
            sampleRate = app.MainApp.waveformConfig.sampleRate; % 1.0007 MHz
            delayTime = app.MainApp.waveformConfig.delayTime;
            mode = app.MainApp.waveformConfig.mode; % Stepped
            clockSource = app.MainApp.waveformConfig.clockSource; % 'OnboardClock' or 'ClkIn'
            triggerOn = app.MainApp.waveformConfig.triggerOn; % true or false
            triggerPort = app.MainApp.waveformConfig.triggerPort; % PFI0 or PFI1
            exportSignalOn = app.MainApp.waveformConfig.exportSignalOn; % true or false
            exportSignalType = app.MainApp.waveformConfig.exportSignalType; % Marker DataMarker
            exportSignalPort = app.MainApp.waveformConfig.exportSignalPort; % PFI0 or PFI1


            app.DeviceIDEditField.Value = resourceID;

            switch mode
                case 'Stepped'
                    app.ArbModeDropDown.Value = 'Stepped';
                case 'Continuous'
                    app.ArbModeDropDown.Value = 'Continuous';
                case 'Burst'
                    app.ArbModeDropDown.Value = 'Burst';
                case 'Single'
                    app.ArbModeDropDown.Value = 'Single';
            end

            switch clockSource
                case 'ClkIn'
                    app.AwgExternalClockPort.Visible = "on";
                    app.AwgExternalClockPort.Value = 'ClkIn';
                    app.SampleClockDropDown.Value = 'External';
                case 'OnboardClock'
                    app.AwgExternalClockPort.Visible = "off";
                    app.SampleClockDropDown.Value = 'Internal';
            end


            if triggerOn
                app.ExternalTriggerDropDown.Value = 'On';
                app.AwgTriggerPort.Visible = 'on';
                app.AwgTriggerPort.Value = triggerPort;

            else
                app.ExternalTriggerDropDown.Value = 'Off';
                app.AwgTriggerPort.Visible = 'off';
            end


            if exportSignalOn
                app.ExportSignalDropDown.Value = 'On';
                app.ExportSignalPort.Visible = 'on';
                app.ExportSignalPort.Value = exportSignalPort;
                app.SignalTypeDropDown.Visible = 'on';
                app.SignalTypeDropDown.Value = exportSignalType;

            else
                app.ExportSignalDropDown.Value = 'Off';
                app.ExportSignalPort.Visible = 'off';
                app.SignalTypeDropDown.Visible = 'off';
            end


            app.ArbGainEditField.Value  = gain;
            app.ArbOffsetEditField.Value = offset;
            app.SampleRateEditField.Value = sampleRate;
            app.DelaynsEditField.Value = delayTime;

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            app.AWGSettingsUIFigure.Position(1) = app.MainApp.RoiImagingModuleUIFigure.Position(1)+app.MainApp.RoiImagingModuleUIFigure.Position(3)+5;
            app.AWGSettingsUIFigure.Position(2) = app.MainApp.RoiImagingModuleUIFigure.Position(2)+app.MainApp.RoiImagingModuleUIFigure.Position(4)-app.AWGSettingsUIFigure.Position(4);

            % 获取Mainapp的AWG Settings,并修改Subapp的选项显示
            app.variableInit();
        end

        % Value changed function: SampleClockDropDown
        function SampleClockDropDownValueChanged(app, event)
            sampleClockSource = app.SampleClockDropDown.Value;
            switch sampleClockSource 
               case 'Internal'
                  app.AwgExternalClockPort.Visible = "off";
                  app.MainApp.waveformConfig.clockSource = 'OnboardClock';
               case 'External'
                  app.AwgExternalClockPort.Visible = "on";
                  app.MainApp.waveformConfig.clockSource = 'ClkIn';
               otherwise
                  app.AwgExternalClockPort.Visible = "off";
                  app.MainApp.waveformConfig.clockSource = 'OnboardClock';
            end
        end

        % Value changed function: ExternalTriggerDropDown
        function ExternalTriggerDropDownValueChanged(app, event)
            value = app.ExternalTriggerDropDown.Value;
            if value == "On"
                app.AwgTriggerPort.Visible = "on";
                app.MainApp.waveformConfig.triggerOn = true;
                app.MainApp.waveformConfig.triggerPort = app.AwgTriggerPort.Value;
            else
                app.AwgTriggerPort.Visible = "off";
                app.MainApp.waveformConfig.triggerOn = false;
            end
        end

        % Value changed function: ExportSignalDropDown
        function ExportSignalDropDownValueChanged(app, event)
            valueExportSignal = app.ExportSignalDropDown.Value;
            if valueExportSignal == "On"
                app.ExportSignalPort.Visible = "on";
                app.SignalTypeDropDown.Visible = "on";
                app.MainApp.waveformConfig.exportSignalOn = true;
                app.MainApp.waveformConfig.exportSignalType = app.SignalTypeDropDown.Value;
                app.MainApp.waveformConfig.exportSignalPort = app.ExportSignalPort.Value;
            else
                app.ExportSignalPort.Visible = "off";
                app.SignalTypeDropDown.Visible = "off";
                app.MainApp.waveformConfig.exportSignalOn = false;
            end
        end

        % Value changed function: ArbModeDropDown
        function ArbModeDropDownValueChanged(app, event)
            value = app.ArbModeDropDown.Value;
            app.MainApp.waveformConfig.mode = value;
        end

        % Value changed function: DeviceIDEditField
        function DeviceIDEditFieldValueChanged(app, event)
            value = app.DeviceIDEditField.Value;
            app.MainApp.waveformConfig.resourceID = value;
        end

        % Value changed function: AwgTriggerPort
        function AwgTriggerPortValueChanged(app, event)
            value = app.AwgTriggerPort.Value;
                            app.MainApp.waveformConfig.triggerPort = value;
        end

        % Value changed function: ExportSignalPort
        function ExportSignalPortValueChanged(app, event)
            value = app.ExportSignalPort.Value;
            app.MainApp.waveformConfig.exportSignalType = value;
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
        value = app.SignalTypeDropDown.Value;
            app.MainApp.waveformConfig.exportSignalPort = value;
        end

        % Value changed function: ArbGainEditField
        function ArbGainEditFieldValueChanged(app, event)
            value = app.ArbGainEditField.Value;
            app.MainApp.waveformConfig.gain = value;
        end

        % Value changed function: ArbOffsetEditField
        function ArbOffsetEditFieldValueChanged(app, event)
            value = app.ArbOffsetEditField.Value;
            app.MainApp.waveformConfig.offset = value;
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            value = app.SampleRateEditField.Value;
            app.MainApp.waveformConfig.sampleRate = value;
        end

        % Value changed function: DelaynsEditField
        function DelaynsEditFieldValueChanged(app, event)
            value = app.DelaynsEditField.Value;
            app.MainApp.waveformConfig.delayTime = value;
        end

        % Close request function: AWGSettingsUIFigure
        function AWGSettingsUIFigureCloseRequest(app, event)
            app.MainApp.AwgSettingsApp = 0;

            try
                delete(app);
            catch
                disp('error');
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AWGSettingsUIFigure = uifigure('Visible', 'off');
            app.AWGSettingsUIFigure.Name = 'AWG Settings';
            app.AWGSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @AWGSettingsUIFigureCloseRequest, true);
            app.DeviceIDEditFieldLabel = uilabel(app.AWGSettingsUIFigure);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.AWGSettingsUIFigure, 'text');
            app.DeviceIDEditField.ValueChangedFcn = createCallbackFcn(app, @DeviceIDEditFieldValueChanged, true);
            app.DeviceIDEditField.Value = 'Dev1';
            app.TriggerModeLabel = uilabel(app.AWGSettingsUIFigure);
            app.TriggerModeLabel.Text = 'Trigger Mode';
            app.ArbModeDropDown = uidropdown(app.AWGSettingsUIFigure);
            app.ArbModeDropDown.Items = {'Continuous', 'Single', 'Burst', 'Stepped'};
            app.ArbModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ArbModeDropDownValueChanged, true);
            app.ArbModeDropDown.Value = 'Stepped';
            app.SampleRateEditFieldLabel = uilabel(app.AWGSettingsUIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SampleRateEditField = uieditfield(app.AWGSettingsUIFigure, 'numeric');
            app.SampleRateEditField.Limits = [10 1000000000];
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Value = 1000000;
            app.GainEditFieldLabel = uilabel(app.AWGSettingsUIFigure);
            app.GainEditFieldLabel.Text = 'Gain (V)';
            app.ArbGainEditField = uieditfield(app.AWGSettingsUIFigure, 'numeric');
            app.ArbGainEditField.Limits = [0.0028175 6];
            app.ArbGainEditField.ValueChangedFcn = createCallbackFcn(app, @ArbGainEditFieldValueChanged, true);
            app.ArbGainEditField.Value = 3.3;
            app.ArbOffsetEditFieldLabel = uilabel(app.AWGSettingsUIFigure);
            app.ArbOffsetEditFieldLabel.Text = 'Offset (V)';
            app.ArbOffsetEditField = uieditfield(app.AWGSettingsUIFigure, 'numeric');
            app.ArbOffsetEditField.Limits = [-3 3];
            app.ArbOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ArbOffsetEditFieldValueChanged, true);
            app.SampleClockLabel = uilabel(app.AWGSettingsUIFigure);
            app.SampleClockLabel.Text = 'Sampling Clock';
            app.SampleClockDropDown = uidropdown(app.AWGSettingsUIFigure);
            app.SampleClockDropDown.Items = {'Internal', 'External'};
            app.SampleClockDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleClockDropDownValueChanged, true);
            app.SampleClockDropDown.Value = 'External';
            app.AwgExternalClockPort = uieditfield(app.AWGSettingsUIFigure, 'text');
            app.AwgExternalClockPort.Value = 'ClkIn';
            app.AwgTriggerPort = uieditfield(app.AWGSettingsUIFigure, 'text');
            app.AwgTriggerPort.ValueChangedFcn = createCallbackFcn(app, @AwgTriggerPortValueChanged, true);
            app.AwgTriggerPort.Value = 'PFI0';
            app.ExportSignalPort = uieditfield(app.AWGSettingsUIFigure, 'text');
            app.ExportSignalPort.ValueChangedFcn = createCallbackFcn(app, @ExportSignalPortValueChanged, true);
            app.ExportSignalPort.Value = 'PFI1';
            app.SignalTypeDropDown = uidropdown(app.AWGSettingsUIFigure);
            app.SignalTypeDropDown.Items = {'Marker', 'DataMarker'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'DataMarker';
            app.ExternalTriggerDropDownLabel = uilabel(app.AWGSettingsUIFigure);
            app.ExternalTriggerDropDownLabel.Text = 'External Trigger';
            app.ExternalTriggerDropDown = uidropdown(app.AWGSettingsUIFigure);
            app.ExternalTriggerDropDown.Items = {'Off', 'On'};
            app.ExternalTriggerDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalTriggerDropDownValueChanged, true);
            app.ExternalTriggerDropDown.Value = 'On';
            app.DelaynsEditFieldLabel = uilabel(app.AWGSettingsUIFigure);
            app.DelaynsEditFieldLabel.Text = 'Delay (ns)';
            app.DelaynsEditField = uieditfield(app.AWGSettingsUIFigure, 'numeric');
            app.DelaynsEditField.ValueChangedFcn = createCallbackFcn(app, @DelaynsEditFieldValueChanged, true);
            app.ExportSignalDropDownLabel = uilabel(app.AWGSettingsUIFigure);
            app.ExportSignalDropDownLabel.Text = 'Export  Signal';
            app.ExportSignalDropDown = uidropdown(app.AWGSettingsUIFigure);
            app.ExportSignalDropDown.Items = {'Off', 'On'};
            app.ExportSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportSignalDropDownValueChanged, true);
            app.ExportSignalDropDown.Value = 'On';
            % Show the figure after all components are created
            app.AWGSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AwgSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AWGSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AWGSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AWGSettingsUIFigure)
        end
    end
end