classdef ScannerSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TriggertypeDropDown       matlab.ui.control.DropDown
        TriggertypeDropDownLabel  matlab.ui.control.Label
        ImagesizeDropDown         matlab.ui.control.DropDown
        ImagesizeDropDownLabel    matlab.ui.control.Label
        UpdateButton              matlab.ui.control.Button
        ScanBackRightSpinner      matlab.ui.control.Spinner
        ScanBackLeftSpinner       matlab.ui.control.Spinner
        ScanPulsePerPixelSpinner  matlab.ui.control.Spinner
        ScanWaitSpinner           matlab.ui.control.Spinner
        PulseperpixelLabel        matlab.ui.control.Label
        WaitpixelsLabel           matlab.ui.control.Label
        ScanBackEditField_2Label  matlab.ui.control.Label
        ScanSpaceLeftLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    
    methods (Access = public)
        
        function variableInit(app)
            imageSize = app.MainApp.scannerConfig.imageSize;
            pulsePerPixel = app.MainApp.scannerConfig.pulsePerPixel;
            scanWait = app.MainApp.scannerConfig.scanWait;
            scanBackLeftPixelTwice = app.MainApp.scannerConfig.scanBackLeftPixelTwice; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            scanBackRightPixelTwice = app.MainApp.scannerConfig.scanBackRightPixelTwice; % unit: pixel
            clockMode = app.MainApp.scannerConfig.clockMode;
            
            % 设置 imageSize

            app.ImagesizeDropDown.Value =imageSize;

            % 设置perlse per pixel
            app.ScanPulsePerPixelSpinner.Value = pulsePerPixel;

            % 设置 scanWait
            app.ScanWaitSpinner.Value = scanWait;

            % 设置 scanBackLeftPixelTwice
            app.ScanBackLeftSpinner.Value = scanBackLeftPixelTwice;

            % 设置 scanBackRightPixelTwice
            app.ScanBackRightSpinner.Value = scanBackRightPixelTwice;

            % 设置 clock mode
            app.TriggertypeDropDown.Value =  clockMode;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
           
            app.MainApp = MainApp;
            app.UIFigure.Position(1) = app.MainApp.UIFigure.Position(1)+app.MainApp.UIFigure.Position(3)+5;
            app.UIFigure.Position(2) = app.MainApp.UIFigure.Position(2);
            % 获取Mainapp的Scanner Settings,并修改Subapp的选项显示
            variableInit(app);

        end

        % Value changed function: ImagesizeDropDown
        function ImagesizeDropDownValueChanged(app, event)
            value = app.ImagesizeDropDown.Value;
            app.MainApp.scannerConfig.imageSize = value;
        end

        % Value changed function: ScanPulsePerPixelSpinner
        function ScanPulsePerPixelSpinnerValueChanged(app, event)
            value = app.ScanPulsePerPixelSpinner.Value;
            app.MainApp.scannerConfig.pulsePerPixel  = value;
        end

        % Value changed function: ScanWaitSpinner
        function ScanWaitSpinnerValueChanged(app, event)
            value = app.ScanWaitSpinner.Value;
            app.MainApp.scannerConfig.scanWait = value;
        end

        % Value changed function: ScanBackLeftSpinner
        function ScanBackLeftSpinnerValueChanged(app, event)
            value = app.ScanBackLeftSpinner.Value;
            app.MainApp.scannerConfig.scanBackLeftPixelTwice = value;
        end

        % Value changed function: ScanBackRightSpinner
        function ScanBackRightSpinnerValueChanged(app, event)
            value = app.ScanBackRightSpinner.Value;
            app.MainApp.scannerConfig.scanBackRightPixelTwice = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.ScannerSettingsApp = [];

            try
                delete(app);
            catch
                disp('error');
            end
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % 读取hSI信号
            try
                hSI = evalin('base', 'hSI');
            catch
                uialert(app.UIFigure,"Please Start Scanimage First",'Warning','Icon','warning');
                return
            end    
            app.ImagesizeDropDown.Value = hSI.hRoiManager.pixelsPerLine; % 图像大小
            app.ScanPulsePerPixelSpinner.Value = hSI.hScan2D.pixelBinFactor; % 一个像素采样几次
            
            scanSpace = round(hSI.hRoiManager.linePeriod/hSI.hScan2D.scanPixelTimeMean-hSI.hRoiManager.pixelsPerLine); %计算Scan Left和Scan Right
            app.ScanBackLeftSpinner.Value = scanSpace;
            app.ScanBackRightSpinner.Value = scanSpace;


            % Update
            app.MainApp.scannerConfig.imageSize  = app.ImagesizeDropDown.Value;
            app.MainApp.scannerConfig.pulsePerPixel  = app.ScanPulsePerPixelSpinner.Value;
            app.MainApp.scannerConfig.scanBackLeftPixelTwice = app.ScanBackLeftSpinner.Value;
            app.MainApp.scannerConfig.scanBackRightPixelTwice = app.ScanBackRightSpinner.Value;
        end

        % Value changed function: TriggertypeDropDown
        function TriggertypeDropDownValueChanged(app, event)
            value = app.TriggertypeDropDown.Value;
            app.MainApp.scannerConfig.clockMode= value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Scanner Settings';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ScanSpaceLeftLabel = uilabel(app.UIFigure);
            app.ScanSpaceLeftLabel.Text = 'Turnaround left*2 (pixels)';
            app.ScanBackEditField_2Label = uilabel(app.UIFigure);
            app.ScanBackEditField_2Label.Text = 'Turnaround right*2 (pixels)';
            app.WaitpixelsLabel = uilabel(app.UIFigure);
            app.WaitpixelsLabel.Text = 'Wait (pixels)';
            app.PulseperpixelLabel = uilabel(app.UIFigure);
            app.PulseperpixelLabel.Text = 'Pulse per pixel';
            app.ScanWaitSpinner = uispinner(app.UIFigure);
            app.ScanWaitSpinner.Limits = [0 Inf];
            app.ScanWaitSpinner.ValueDisplayFormat = '%.0f';
            app.ScanWaitSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanWaitSpinnerValueChanged, true);
            app.ScanWaitSpinner.Value = 46;
            app.ScanPulsePerPixelSpinner = uispinner(app.UIFigure);
            app.ScanPulsePerPixelSpinner.Limits = [1 Inf];
            app.ScanPulsePerPixelSpinner.ValueDisplayFormat = '%.0f';
            app.ScanPulsePerPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanPulsePerPixelSpinnerValueChanged, true);
            app.ScanPulsePerPixelSpinner.Value = 1;
            app.ScanBackLeftSpinner = uispinner(app.UIFigure);
            app.ScanBackLeftSpinner.Limits = [0 Inf];
            app.ScanBackLeftSpinner.ValueDisplayFormat = '%.0f';
            app.ScanBackLeftSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanBackLeftSpinnerValueChanged, true);
            app.ScanBackLeftSpinner.Value = 28;
            app.ScanBackRightSpinner = uispinner(app.UIFigure);
            app.ScanBackRightSpinner.Limits = [0 Inf];
            app.ScanBackRightSpinner.ValueDisplayFormat = '%.0f';
            app.ScanBackRightSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanBackRightSpinnerValueChanged, true);
            app.ScanBackRightSpinner.Value = 28;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ImagesizeDropDownLabel = uilabel(app.UIFigure);
            app.ImagesizeDropDownLabel.Text = 'Image size';
            app.ImagesizeDropDown = uidropdown(app.UIFigure);
            app.ImagesizeDropDown.Items = {'512*512', '256*256'};
            app.ImagesizeDropDown.ItemsData = [512 256];
            app.ImagesizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesizeDropDownValueChanged, true);
            app.ImagesizeDropDown.Value = 512;
            app.TriggertypeDropDownLabel = uilabel(app.UIFigure);
            app.TriggertypeDropDownLabel.Text = 'Trigger type';
            app.TriggertypeDropDown = uidropdown(app.UIFigure);
            app.TriggertypeDropDown.Items = {'Line clock', 'Frame clock'};
            app.TriggertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggertypeDropDownValueChanged, true);
            app.TriggertypeDropDown.Value = 'Line clock';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScannerSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end