classdef TiffViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TiffViewerUIFigure   matlab.ui.Figure
        ContrastSlider       matlab.ui.control.RangeSlider
        ContrastSliderLabel  matlab.ui.control.Label
        filenameLabel        matlab.ui.control.Label
        SliderLabel          matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        LoadTiffStackButton  matlab.ui.control.Button
        TiffRawUIAxes        matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
    end

    
    properties 
        dir = ''; % app当前运行路径
        % TODO 把这些tiff变量用tiff结构体数组来封装，方便查看
        last_selected_folder = '';% 上次选中的文件夹
        tiff_path =''; %tiff路径
        tiff_filename = '';
        tiff_memmap % Description
        tiff_stack_data (:,:,:);
        tiff_stack_data_adjusted (:,:,:);
        tiff_all_frames;
        tiff_current_frame = 1;
        TiffRawUIAxes_image_layer;
        TiffRawUIAxes_mask_layer;
        TiffRawUIAxes_label_handles;
        tiff_contrast_value  = [0 400];
        tiff_seg_data;
        tiff_seh_data_autoscale_contrast logical = false;
        tiff_mean_img;
        tiff_std_img;
        tiff_max_img;
        tiff_seg_img_handles;
        plot_signal_handles;


    end
    
    properties (Access = private)
        
    end
    

    properties 
        signal_raw;
        signal_delta;% Description
        signal_bias = 2.2204e-16;
    end
    
    properties
        seg_enable;
        seg_adjust_enable;

        Pan;
    end
    
    methods 
        function config_read(app)
            text = fileread(fullfile(app.dir,'Viewer_config.json'));
            config = jsondecode(text);

            if isfield(config, 'last_tiff_path')
                app.last_selected_folder = config.last_tiff_path;
            end
        end

        function config_save(app)
            config.last_tiff_path = app.last_selected_folder;
            json_data = jsonencode(config,'PrettyPrint',true);
            % disp(fullfile(app.dir,'config.json'));
            fileID = fopen(fullfile(app.dir,'Viewer_config.json'), 'w');
            fprintf(fileID, "%s",json_data);
            fclose(fileID);
        end


        function mask_update(app,~,~)
            TiffMaskCheckBoxValueChanged(app);
        end

    end

    
    methods (Access = private)
        
        function save_mask(app)
            app.MaskUIAxes.XLim = app.MaskUIAxes.UserData.origin_xlim;
            app.MaskUIAxes.YLim = app.MaskUIAxes.UserData.origin_ylim;
            XAxis_Visible = app.PlotSignalUIAxes.XAxis.Visible;
            YAxis_Visible = app.PlotSignalUIAxes.XAxis.Visible;
            app.PlotSignalUIAxes.XAxis.Visible = 'off';
            app.PlotSignalUIAxes.YAxis.Visible = 'off';
            exportgraphics(app.MaskUIAxes, ...
                fullfile(app.last_selected_folder, ...
                [app.tiff_filename,'_roi_mask.png']), ...
                'Resolution',300);
            app.PlotSignalUIAxes.XAxis.Visible = XAxis_Visible ;
            app.PlotSignalUIAxes.YAxis.Visible = YAxis_Visible;


            % save matfile
            data.three_fold_mask = app.DrawROI.three_fold_mask;
            data.three_fold_colored_mask = app.DrawROI.three_fold_colored_mask;
            data.move_down = app.DrawROI.move_down;
            data.move_right = app.DrawROI.move_right;
            data.mask = app.DrawROI.mask;
            data.raw_sig = app.signal_raw;
            data.dff_sig = app.signal_delta;
            save(fullfile(app.last_selected_folder,[app.tiff_filename,'_roi.mat']), ...
                "-struct", ...
                "data");
            % save roi mask to imageJ
            utils.save_roi_to_imagej(app.DrawROI.mask,fullfile(app.last_selected_folder,strcat(app.tiff_filename,'_roi.zip')));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("+assets\")
            app.dir = utils.GetExecutableFolder();
            addpath('libs/FastTiffReadWrite')
            if isfile(fullfile(app.dir,'config.json')) 
                app.config_read()
            end

            app.TiffRawUIAxes.YDir = "reverse";

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.tiff_current_frame = round(value);
            app.SliderLabel.Text = sprintf("%d/%d",app.tiff_current_frame,app.tiff_all_frames);
            app.TiffRawUIAxes_image_layer.CData = app.tiff_memmap.Data(app.tiff_current_frame).channel1';
            drawnow
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            value = event.Value;
            app.tiff_current_frame = round(value);
            app.SliderLabel.Text = sprintf("%d/%d",app.tiff_current_frame,app.tiff_all_frames);
            app.TiffRawUIAxes_image_layer.CData =app.tiff_memmap.Data(app.tiff_current_frame).channel1';
            drawnow
        end

        % Close request function: TiffViewerUIFigure
        function TiffViewerUIFigureCloseRequest(app, event)
            % 关闭app之前需要保存config
            app.config_save();

            % close app
            delete(app)

        end

        % Button pushed function: LoadTiffStackButton
        function LoadTiffStackButtonPushed(app, event)

            [filename,path] = utils.select_file({'*.tif'},app.last_selected_folder);

            if filename ~= 0
                % create progress dialog
                d = uiprogressdlg(app.TiffViewerUIFigure,'Title','Loading Image',...
                    'Indeterminate','on');
                drawnow

                app.filenameLabel.Text = filename;

                % save path for next click
                app.last_selected_folder = path;

                [~,name,~] = fileparts(filename);
                app.tiff_filename = name;

                % load tiff

                app.tiff_path = fullfile(app.last_selected_folder,filename);
                app.tiff_current_frame  = 1;
                try
                    app.tiff_memmap= memory_map_tiff(app.tiff_path,[],1,true); % 不能直接memory_map_tiff(app.tiff_path,[],1,true).Data，这样相当于读取所有数据会非常卡
                    app.tiff_all_frames = length(app.tiff_memmap.Data);
                catch ME
                    % 捕获并显示错误信息
                    errordlg(ME.message, 'Error');
                    fprintf(2,'%s\n', ME.getReport('extended'));
                    app.tiff_memmap = [];
                    return 
                end

                if app.tiff_all_frames > 1
                    app.Slider.Limits = [1,app.tiff_all_frames];
                    app.Slider.Enable = 'on';
                else
                    app.Slider.Enable = 'off';
                    
                end
                app.SliderLabel.Text = sprintf("%d/%d",app.tiff_current_frame,app.tiff_all_frames);


                % clear old signal axes
                cla(app.TiffRawUIAxes);


                % update axes
                app.TiffRawUIAxes_image_layer = imshow(app.tiff_memmap.Data(app.tiff_current_frame).channel1',[],'parent',app.TiffRawUIAxes,'border','tight','initialmagnification','fit');
                app.ContrastSlider.Limits = [0,double(max(app.TiffRawUIAxes_image_layer.CData,[],'all'))];
                app.ContrastSlider.Value = get(app.TiffRawUIAxes,"Clim"); 
                drawnow


                % close the dialog box
                close(d);
                

            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            % 更改对比度slider
            value = app.ContrastSlider.Value;
            if value(1) ~= value(2)
                app.TiffRawUIAxes.CLim = value;
            end

        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            % 更改对比度slider
            value = event.Value;
            if value(1) ~= value(2)
                app.TiffRawUIAxes.CLim = value;
            end
        end

        % Button down function: TiffRawUIAxes
        function TiffRawUIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TiffViewerUIFigure = uifigure('Visible', 'off');
            app.TiffViewerUIFigure.Name = 'TiffViewer';
            app.TiffViewerUIFigure.Icon = fullfile(pathToMLAPP, 'image.svg');
            app.TiffViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @TiffViewerUIFigureCloseRequest, true);
            app.TiffRawUIAxes = uiaxes(app.TiffViewerUIFigure);
            app.TiffRawUIAxes.XLimitMethod = 'tight';
            app.TiffRawUIAxes.YLimitMethod = 'tight';
            app.TiffRawUIAxes.ZLimitMethod = 'tight';
            app.TiffRawUIAxes.XTick = [];
            app.TiffRawUIAxes.YTick = [];
            app.TiffRawUIAxes.ButtonDownFcn = createCallbackFcn(app, @TiffRawUIAxesButtonDown, true);
            app.LoadTiffStackButton = uibutton(app.TiffViewerUIFigure, 'push');
            app.LoadTiffStackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTiffStackButtonPushed, true);
            app.LoadTiffStackButton.Icon = fullfile(pathToMLAPP, 'folder-open.svg');
            app.LoadTiffStackButton.Text = '';
            app.Slider = uislider(app.TiffViewerUIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.SliderLabel = uilabel(app.TiffViewerUIFigure);
            app.SliderLabel.Text = '1/1000';
            app.filenameLabel = uilabel(app.TiffViewerUIFigure);
            app.filenameLabel.Text = 'filename';
            app.ContrastSliderLabel = uilabel(app.TiffViewerUIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.TiffViewerUIFigure, 'range');
            app.ContrastSlider.Limits = [0 2500];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.Value = [0 400];
            app.ContextMenu = uicontextmenu(app.TiffViewerUIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            % Assign app.ContextMenu
            app.TiffRawUIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.TiffViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TiffViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TiffViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TiffViewerUIFigure)
        end
    end
end