classdef roi_imaging_module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadStructureImageMenu       matlab.ui.container.Menu
        LoadExternalmaskMenu         matlab.ui.container.Menu
        SaveConfigMenu               matlab.ui.container.Menu
        LoadConfigMenu               matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        AWGSettingsMenu              matlab.ui.container.Menu
        ScannerSettingsMenu          matlab.ui.container.Menu
        ROIMaskSettingsMenu          matlab.ui.container.Menu
        AddonsMenu                   matlab.ui.container.Menu
        PowerCaculateMenu            matlab.ui.container.Menu
        AWGControlMenu               matlab.ui.container.Menu
        ROIImagingSimulationMenu     matlab.ui.container.Menu
        CustomDrawMenu_2             matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        SimulationToggleTool         matlab.ui.container.toolbar.ToggleTool
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StructureImagingPanel        matlab.ui.container.Panel
        RegularImagingButton         matlab.ui.control.Button
        Laser1on9offButton           matlab.ui.control.Button
        StructureImagingLamp         matlab.ui.control.Lamp
        SettingsPanel                matlab.ui.container.Panel
        ScanimageButton              matlab.ui.control.StateButton
        ConfigurationFileEditField   matlab.ui.control.EditField
        ConfigurationEditFieldLabel  matlab.ui.control.Label
        AdvancedSettingsOpenButton   matlab.ui.control.Button
        ConfigFileSelectButton       matlab.ui.control.Button
        isConnectedLabel             matlab.ui.control.Label
        AWGStatusLabel               matlab.ui.control.Label
        AwgConnectButton             matlab.ui.control.Button
        ROIImagingPanel              matlab.ui.container.Panel
        RealtimeregistrationButton   matlab.ui.control.Button
        ChannelDropDown_2            matlab.ui.control.DropDown
        ROIImagingLamp               matlab.ui.control.Lamp
        AbortButton                  matlab.ui.control.Button
        LaserROIImagingButton        matlab.ui.control.Button
        ManualcorrectionPanel        matlab.ui.container.Panel
        ROIMaskSettingsButton        matlab.ui.control.Button
        ROIMaskClearButton           matlab.ui.control.Button
        SaveMaskButton               matlab.ui.control.Button
        LoadMaskButton               matlab.ui.control.Button
        ROIdilateSpinner             matlab.ui.control.Spinner
        ROIdilateSpinnerLabel        matlab.ui.control.Label
        MaskOnCheckBox               matlab.ui.control.CheckBox
        ROIMaskDropDown              matlab.ui.control.DropDown
        ROIMaskDropDownLabel         matlab.ui.control.Label
        NeuronSegmentationPanel      matlab.ui.container.Panel
        StructureTypeDropDown        matlab.ui.control.DropDown
        LoadSegImageButton           matlab.ui.control.Button
        RunModelButton               matlab.ui.control.Button
        ThresholdSpinner             matlab.ui.control.Spinner
        ThresholdSpinnerLabel        matlab.ui.control.Label
        ModelsDropDown               matlab.ui.control.DropDown
        ModelsDropDownLabel          matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ROIIDLabel                   matlab.ui.control.Label
        ShowROINumbersCheckBox       matlab.ui.control.CheckBox
        FrameSliderLabel             matlab.ui.control.Label
        FrameSlider                  matlab.ui.control.Slider
        ContrastLabel                matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        AdjustButton                 matlab.ui.control.StateButton
        UIAxesHomeButton             matlab.ui.control.Button
        ROIRatioEditField            matlab.ui.control.NumericEditField
        ROIRatioEditField_2Label     matlab.ui.control.Label
        ROIsEditField                matlab.ui.control.NumericEditField
        ROIsEditFieldLabel           matlab.ui.control.Label
        PowerCaculatePanel           matlab.ui.container.Panel
        UpdateButton                 matlab.ui.control.Button
        PowerCostEditField           matlab.ui.control.NumericEditField
        PowerCoseLabel               matlab.ui.control.Label
        ROIPowerEditField            matlab.ui.control.NumericEditField
        ROIPowermWEditField_2Label   matlab.ui.control.Label
        StructurePowerEditField      matlab.ui.control.NumericEditField
        MHzPowermWEditFieldLabel     matlab.ui.control.Label
        ImagingPowerEditField        matlab.ui.control.NumericEditField
        LaserPowermWLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        % Sub APP
        AwgSettingsApp;% sub-app for awg settings
        ScannerSettingsApp; % sub-app for scanner settings
        AwgControlApp;
        SimulationApp;
        PowerCaculateAPP;
        ROIMaskSettingsApp;

        roiMask % Dilate之后

        % AWG
        awgDevice= ividev.NIFGEN.empty; % AWG Device Object, default:empty

        % Scaniamge变量
        hSI = scanimage.SI.empty;
        hSICtl = scanimage.SIController.empty;

        % components
        StructureRebuilder = components.ScanimageRealtimeRebuildAvg.empty;%监听scanimage进行1/10成像
        DrawROI = components.DrawROI.empty; %  手动圈选ROI模块
        Seg =components.Segmentation.empty;
    end


    % path and folder
    properties
        folder; % app folder
        lastStructureImagepath = '';  % save last selected path of structure
        lastRoiMaskPath= ''; % save last selected path of roi mask
        lastConfigPath = ''; % save last selected path of config file
        last_seg_tiff_folder = ''; % save last selected path of avg tif file
        seg_img_layer;
        seg_img_stack;
        img_avg_Ch1;% Description
        img_avg_Ch2;
        img_seg_data; % 用于分割的图像数据
        img_seg_filename;
        img_seg_fname % Description
    end

    % listener
    properties (Access = private)
        structureListener = event.listener.empty;% listener for structure imaging
        
        img_seg_ext
    end

    % AWG Settings,Constant =true 让外部可以直接访问
    properties (Access = public)
        defaultConfig = struct();
        waveformConfig = struct();
        scannerConfig = struct();
    end

    % 配准
    properties (Access=public)
        refImg;
    end


    methods (Access = private)
        function windowButtonDown(app, ~, ~)
            if ~isempty(app.DrawROI)

                currentPosition = app.UIFigure.UserData.activeAxes.CurrentPoint;
                x = currentPosition(1,1);
                y = currentPosition(1,2);

                if x >= app.UIFigure.UserData.activeAxes.XLim(1) && x <= app.UIFigure.UserData.activeAxes.XLim(2) && ...
                        y >= app.UIFigure.UserData.activeAxes.YLim(1) && y <= app.UIFigure.UserData.activeAxes.YLim(2)

                    % 强制平移模式下，当左键点击时，进入平移状态（按空格+左键）
                    if app.UIFigure.UserData.forcePanMode && strcmp(app.UIFigure.SelectionType, 'normal')
                        app.UIFigure.UserData.activeAxes.UserData.status = "axes_paning";
                        app.UIFigure.UserData.Pan.previous_point = app.UIFigure.UserData.activeAxes.CurrentPoint;
                        return;
                    end

                    switch app.UIFigure.SelectionType
                        case 'normal' % 左键点击
                            % 可取消当前绘制
                            if strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "handroi_drawing")
                                app.DrawROI.handroi_cancel();
                            end
                            app.UIFigure.UserData.CtrlPressed = false;
                            app.DrawROI.select_roi(x, y);
                            % 如果正在添加规则ROI，不进入平移模式
                            if ~app.DrawROI.is_drag_active() && ~app.DrawROI.is_roi_editing() && ~app.DrawROI.is_adding_regular_roi()
                                app.UIFigure.UserData.activeAxes.UserData.status = "axes_paning";
                                app.UIFigure.UserData.Pan.previous_point = app.UIFigure.UserData.activeAxes.CurrentPoint;
                            end
                        case 'alt' % 代表 Ctrl+ 左键，或者单击右键；
                            if app.UIFigure.UserData.CtrlPressed % Ctrl + Click 删除ROI
                                app.DrawROI.delete_roi(x, y);
                            else % 右键点击，开始绘制ROI
                                % 只有当前轴允许绘制时才开始绘制
                                if strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "idle") && app.DrawROI.drawing_enabled(app.DrawROI.active_axes_index)
                                    app.DrawROI.handroi_start(x, y);
                                end
                            end
                        case 'open' % 双击鼠标左键
                            % 检查是否点击在某个ROI上，如果是，则开始编辑该ROI
                            if app.DrawROI.is_adding_regular_roi()
                                app.DrawROI.finish_adding_regular_roi(true);
                            else
                                app.DrawROI.start_edit_roi(x, y);
                            end

                        case 'extend' % 代表 Shift+ 左键、鼠标中键或左右键一起按
                            return
                    end
                end
            end
        end

        function windowMotion(app, ~, ~)
            if isempty(app.UIFigure.UserData.activeAxes)
                return;
            end
            if isempty(app.DrawROI)
                return;
            end

            % 判断鼠标是否在UIAxes内

            currentPosition = app.UIFigure.UserData.activeAxes.CurrentPoint;
            x = currentPosition(1,1);
            y = currentPosition(1,2);

            if  x >= app.UIFigure.UserData.activeAxes.XLim(1) && x <= app.UIFigure.UserData.activeAxes.XLim(2) && ...
                    y >= app.UIFigure.UserData.activeAxes.YLim(1) && y <= app.UIFigure.UserData.activeAxes.YLim(2)
                app.UIFigure.UserData.mouseInAxes = true;
                switch app.UIFigure.UserData.activeAxes.UserData.status
                    case "axes_paning"
                        % 如果正在添加ROI，则不执行平移
                        % if ~app.DrawROI.is_adding_regular_roi()
                        app.pan_move();
                        % end
                    case "handroi_drawing"
                        if ~isempty(app.DrawROI)
                            app.DrawROI.handroi_draw(x, y);
                        end
                    otherwise
                        % 使用DrawROI的拖拽方法
                        if ~isempty(app.DrawROI)
                            app.DrawROI.drag_move(x, y);
                        end
                end
            else
                app.UIFigure.UserData.mouseInAxes = false;
            end
        end

        function pan_move(app)
            current_position = app.UIFigure.UserData.activeAxes.CurrentPoint;
            xlim_range = get(app.UIFigure.UserData.activeAxes, 'xlim');
            ylim_range = get(app.UIFigure.UserData.activeAxes, 'ylim');
            delta_points = current_position - app.UIFigure.UserData.Pan.previous_point;
            set(app.UIFigure.UserData.activeAxes, 'Xlim', xlim_range - delta_points(1));
            set(app.UIFigure.UserData.activeAxes, 'Ylim', ylim_range - delta_points(3));
            app.UIFigure.UserData.Pan.previous_point = app.UIFigure.UserData.activeAxes.CurrentPoint;
        end

        function windowScrollWheel(app, ~, event)
            if isempty(app.UIFigure.UserData.activeAxes)
                return;
            end
            currentPosition = app.UIFigure.UserData.activeAxes.CurrentPoint;
            x = currentPosition(1,1);
            y = currentPosition(1,2);
            if x >= app.UIFigure.UserData.activeAxes.XLim(1) && x <= app.UIFigure.UserData.activeAxes.XLim(2) && ...
                    y >= app.UIFigure.UserData.activeAxes.YLim(1) && y <= app.UIFigure.UserData.activeAxes.YLim(2)
                if event.VerticalScrollCount > 0
                    scale = 1.1;
                else
                    scale = 1/1.1;
                end
                xlim_range = get(app.UIFigure.UserData.activeAxes, 'xlim');
                ylim_range = get(app.UIFigure.UserData.activeAxes, 'ylim');
                app.UIFigure.UserData.activeAxes.XLim = (xlim_range - x) * scale + x;
                app.UIFigure.UserData.activeAxes.YLim = (ylim_range - y) * scale + y;
            end
        end

        function windowButtonUp(app, ~, ~)
            % 只有当DrawROI对象存在且处于拖拽模式时，才停止拖拽
            if ~isempty(app.DrawROI) && app.DrawROI.is_drag_active()
                app.DrawROI.stop_drag();
            end

            if strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "axes_paning")
                % 无论是否在强制平移模式，松开鼠标左键后都应停止平移
                app.UIFigure.UserData.activeAxes.UserData.status = "idle";
                % 但在强制平移模式下保持手形光标
                if app.UIFigure.UserData.forcePanMode
                    set(app.UIFigure, 'Pointer', 'hand');
                else
                    set(app.UIFigure, 'Pointer', 'arrow');
                end
            end
        end

        function keyPress(app, ~, event)
            switch event.Key
                case 'control'
                    app.UIFigure.UserData.CtrlPressed = true;
                case 'shift'
                    app.UIFigure.UserData.ShiftPressed = true;
                case 'alt'
                    app.UIFigure.UserData.AltPressed = true;
                case 'space' % 空格键按下，激活强制平移模式
                    app.UIFigure.UserData.SpacePressed = true;
                    app.UIFigure.UserData.forcePanMode = true;
                    set(app.UIFigure, 'Pointer', 'hand'); % 改变鼠标指针样式以提示用户

                case 'delete'
                    if app.DrawROI.selected_roi_idx > 0
                        app.DrawROI.delete_selected_roi();
                    end
                    if app.DrawROI.is_adding_regular_roi()
                        app.DrawROI.cancel_adding_regular_roi();
                    end
                case 'uparrow'
                    % 只有当鼠标在UIAxes区域内时才响应方向键
                    if app.UIFigure.UserData.mouseInAxes
                        if ~app.UIFigure.UserData.ShiftPressed
                            app.DrawROI.move_roi(0, -1);
                        else
                            app.DrawROI.move_roi(0, -5);
                        end
                    end
                case 'downarrow'
                    % 只有当鼠标在UIAxes区域内时才响应方向键
                    if app.UIFigure.UserData.mouseInAxes
                        if ~app.UIFigure.UserData.ShiftPressed
                            app.DrawROI.move_roi(0, 1);
                        else
                            app.DrawROI.move_roi(0, 5);
                        end
                    end
                case 'leftarrow'
                    % 只有当鼠标在UIAxes区域内时才响应方向键
                    if app.UIFigure.UserData.mouseInAxes
                        if ~app.UIFigure.UserData.ShiftPressed
                            app.DrawROI.move_roi(-1, 0);
                        else
                            app.DrawROI.move_roi(-5, 0);
                        end
                    end
                case 'rightarrow'
                    % 只有当鼠标在UIAxes区域内时才响应方向键
                    if app.UIFigure.UserData.mouseInAxes
                        if ~app.UIFigure.UserData.ShiftPressed
                            app.DrawROI.move_roi(1, 0);
                        else
                            app.DrawROI.move_roi(5, 0);
                        end
                    end
                case 'return'
                    % 检查是否在编辑ROI模式
                    if app.DrawROI.is_roi_editing()
                        app.DrawROI.finish_edit_roi(true);
                        % 检查是否在添加规则ROI模式
                    elseif app.DrawROI.is_adding_regular_roi()
                        app.DrawROI.finish_adding_regular_roi(true);
                        if app.ShowROINumbersCheckBox.Value
                            app.DrawROI.show_roi_numbers();
                        end
                        % 使用活动轴的状态判断，而不是固定使用第一个轴
                    elseif strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "handroi_drawing")
                        app.DrawROI.finish_drawing();
                        if app.ShowROINumbersCheckBox.Value
                            app.DrawROI.show_roi_numbers();
                        end
                    end
                case 'escape'
                    % 检查是否在编辑ROI模式
                    if app.DrawROI.is_roi_editing()
                        app.DrawROI.cancel_edit_roi();
                        % 检查是否在添加规则ROI模式
                    elseif app.DrawROI.is_adding_regular_roi()
                        app.DrawROI.cancel_adding_regular_roi();
                        % 使用活动轴的状态判断，而不是固定使用第一个轴
                    elseif strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "handroi_drawing")
                        app.DrawROI.handroi_cancel();
                    end
            end
        end

        function keyRelease(app, ~, event)
            switch event.Key
                case 'control'
                    app.UIFigure.UserData.CtrlPressed = false;
                case 'shift'
                    app.UIFigure.UserData.ShiftPressed = false;
                case 'alt'  % Alt键释放，退出强制平移模式
                    app.UIFigure.UserData.AltPressed = false;
                    app.UIFigure.UserData.forcePanMode = false;
                    set(app.UIFigure, 'Pointer', 'arrow'); % 恢复默认鼠标指针
                    % 如果没有按住鼠标左键，则恢复idle状态
                    if strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "axes_paning")
                        % 检查鼠标左键是否按下
                        if ~strcmp(get(app.UIFigure, 'SelectionType'), 'normal')
                            app.UIFigure.UserData.activeAxes.UserData.status = "idle";
                        end
                    end
                case 'space'  % 空格键释放，退出强制平移模式
                    app.UIFigure.UserData.SpacePressed = false;
                    app.UIFigure.UserData.forcePanMode = false;
                    set(app.UIFigure, 'Pointer', 'arrow'); % 恢复默认鼠标指针
                    % 如果没有按住鼠标左键，则恢复idle状态
                    if strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "axes_paning")
                        % 检查鼠标左键是否按下
                        if ~strcmp(get(app.UIFigure, 'SelectionType'), 'normal')
                            app.UIFigure.UserData.activeAxes.UserData.status = "idle";
                        end
                    end

                case 'escape'
                    app.UIFigure.UserData.CtrlPressed = false;
                    app.UIFigure.UserData.ShiftPressed = false;
                    app.UIFigure.UserData.AltPressed = false;
                    app.UIFigure.UserData.SpacePressed = false; % 也重置空格键状态
                    app.UIFigure.UserData.forcePanMode = false;
                    set(app.UIFigure, 'Pointer', 'arrow');
                    if strcmp(app.UIFigure.UserData.activeAxes.UserData.status, "axes_paning")
                        app.UIFigure.UserData.activeAxes.UserData.status = "idle";
                    end
            end
        end


        function create_structure_pulse(app)
            % create pulse for structure imaging
            imageSize = app.scannerConfig.imageSize;

            % generate ROI pulse for each frame
            % 循环10次，创建waveformHandlesArray
            n = 10;
            waveformHandlesArray = cell(1,n);

            colNum = floor(imageSize/n);
            for i=1:n
                % 创建一个黑色的图像矩阵
                temp_mask = repmat(app.defaultConfig.pulseOff,imageSize, imageSize);

                % 计算本次要填充的列
                start = (i-1)*colNum+1;

                % 将这些列填充为白色
                if i == 10
                    % 如果是最后一列，则最后一次填充剩余的所有列
                    temp_mask(:, start:end) = app.defaultConfig.pulseOn;
                else
                    % 如果是前9次，则一次填充colNum列
                    temp_mask(:, start:start+colNum-1) = app.defaultConfig.pulseOn;
                end


                framePulse = create_frame_roi_pulse(app,temp_mask);

                % create waveform
                waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

                % add to waveformHandlesArray
                waveformHandlesArray{i} = waveformHandle;
            end
            waveformHandlesArray = cell2mat(waveformHandlesArray); % matlab 循环用cell 存储，然后再转化为mat矩阵

            sequenceLength = n; % seq 数
            loopCountsArray = ones(1,n); % 设置每个waveform的输出
            waveformSize = numel(framePulse);
            sampleCountsArray = repmat(waveformSize,1,n); % 每个waveform长度
            markerLocationArray = repmat(-1,1,n); % 是否输出markerLocation

            configureOutputMode(app.awgDevice,"OUTPUT_SEQ");
            [~, sequenceHandle] = createAdvancedArbSequence(app.awgDevice, sequenceLength, waveformHandlesArray, loopCountsArray, sampleCountsArray, markerLocationArray); % 创建sequenceHandle

            % generate Arb waveform
            awg.create_arb_sequence(app.awgDevice,sequenceHandle,app.waveformConfig);
        end

        function framePulse = create_frame_roi_pulse(app,roiMask)

            % Read parameters
            pulsePerPixel = app.scannerConfig.pulsePerPixel;
            scanBackLeftPixelTwice = app.scannerConfig.scanBackLeftPixelTwice * pulsePerPixel; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            scanBackRightPixelTwice = app.scannerConfig.scanBackRightPixelTwice * pulsePerPixel; % unit: pixel
            imageSize = app.scannerConfig.imageSize;
            scanWait = app.scannerConfig.scanWait; % unit: pulse


            % Generate ROI mask
            roiMaskPulse = repelem(roiMask, 1, pulsePerPixel); % Each pixel has several pulses, so the signal needs to be repeated
            roiMaskPulse(2:2:end, :) = fliplr(roiMaskPulse(2:2:end, :)); % Because of bidirectional scanning, the laser is scanned from left to right in the first line, and the second line is directly scanned from right, so even rows need to be mirrored

            % Add scan right to odd rows
            oddLineSignal = [roiMaskPulse(1:2:end, :) repmat(app.defaultConfig.pulseOff, imageSize / 2, scanBackRightPixelTwice)];

            % Add scan left to even rows
            evenLineSignal = [roiMaskPulse(2:2:end, :) repmat(app.defaultConfig.pulseOff, imageSize / 2, scanBackLeftPixelTwice)];

            % Then the odd row matrix and the even row matrix are pasted together in sequence and reduced to 1*n (considering the impact of changing the length of the array dynamically in the loop on performance, so use this method to optimize)
            signalPulse = reshape([oddLineSignal evenLineSignal]', 1, []);

            % Compose the final framePusle
            waitPulse = repmat(app.defaultConfig.pulseOff, 1, scanWait); % Add wait time in front
            scanleftFirstPulse = repmat(app.defaultConfig.pulseOff, 1, round(scanBackLeftPixelTwice / 2));
            framePulse = [waitPulse, scanleftFirstPulse, signalPulse(1:end - round(scanBackLeftPixelTwice / 2))]; % The first scanleft is missing, the last scanleft is extra

            % Actually, do not need to consider Flyback, but the length of the waveform must be an integer multiple of 4, so it needs to be supplemented
            if mod(length(framePulse), 4) ~= 0
                extraNum = 4 - mod(length(framePulse), 4);
                framePulse = [framePulse, repmat(app.defaultConfig.pulseOff, 1, extraNum)];
            end
        end

        function linePulse = create_line_roi_pulse(app, roiMaskLine, lineIndex)
            % Create pulse for a single line based on ROI mask

            % Read parameters
            pulsePerPixel = app.scannerConfig.pulsePerPixel;
            scanWait = app.scannerConfig.scanWait;
            scanBackLeftPixelTwice = app.scannerConfig.scanBackLeftPixelTwice * pulsePerPixel;
            scanBackRightPixelTwice = app.scannerConfig.scanBackRightPixelTwice * pulsePerPixel;
            imageWidth = app.scannerConfig.imageSize; % Assuming imageSize is square

            % Generate ROI mask pulse for the line
            roiMaskLinePulse = repelem(roiMaskLine, 1, pulsePerPixel); % Repeat for pulsePerPixel

            % Handle scan direction and add scan back time
            if mod(lineIndex, 2) == 1 % Odd lines (1, 3, 5...) - Scan Left to Right
                lineSignal = [repmat(app.defaultConfig.pulseOff, 1, scanWait), repmat(app.defaultConfig.pulseOff, 1, round(scanBackLeftPixelTwice/2)), roiMaskLinePulse, repmat(app.defaultConfig.pulseOff, 1, round(scanBackRightPixelTwice/2))];
            else % Even lines (2, 4, 6...) - Scan Right to Left
                roiMaskLinePulse = fliplr(roiMaskLinePulse); % Flip for right-to-left scan
                lineSignal = [repmat(app.defaultConfig.pulseOff, 1, scanWait), repmat(app.defaultConfig.pulseOff, 1, round(scanBackRightPixelTwice/2)), roiMaskLinePulse, repmat(app.defaultConfig.pulseOff, 1, round(scanBackLeftPixelTwice/2))];
            end

            % Ensure waveform length is a multiple of 4
            if mod(length(lineSignal), 4) ~= 0
                extraNum = 4 - mod(length(lineSignal), 4);
                lineSignal = [lineSignal, repmat(app.defaultConfig.pulseOff, 1, extraNum)];
            end
            linePulse = lineSignal;
        end

        function awg_output_roi_lines_pulse(app)
            % Configure AWG to output ROI pulses line by line using sequence mode

            imageHeight = app.scannerConfig.imageSize; % Assuming imageSize is square

            % Determine ROI mask based on pulse logic
            if ~app.defaultConfig.pulseOn
                app.roiMask = utils.active_low_logic(app.DrawROI.binary_mask);
            else
                app.roiMask = app.DrawROI.binary_mask;
            end

            % Create waveform handles for each line
            n = imageHeight; % Number of lines
            waveformHandlesArray = cell(1, n);
            sampleCountsArray = zeros(1, n); % Store actual length of each line pulse

            for i = 1:n
                linePulse = create_line_roi_pulse(app, app.roiMask(i,:), i);

                sampleCountsArray(i) = numel(linePulse); % Store length
                % disp(size(linePulse))
                % Create waveform handle for the line pulse
                waveformHandle = awg.create_waveform_handle(app.awgDevice, linePulse);
                waveformHandlesArray{i} = waveformHandle;
            end

            waveformHandlesArray = cell2mat(waveformHandlesArray);

            % Configure sequence parameters
            sequenceLength = n;
            loopCountsArray = ones(1, n); % Output each line waveform once per trigger
            markerLocationArray = repmat(-1, 1, n); % No specific marker locations needed here

            % Configure AWG for sequence output mode
            configureOutputMode(app.awgDevice, "OUTPUT_SEQ");

            % Create and configure the sequence
            [~, sequenceHandle] = createAdvancedArbSequence(app.awgDevice, sequenceLength, waveformHandlesArray, loopCountsArray, sampleCountsArray, markerLocationArray);

            % Generate Arb sequence output (triggered by line clock)
            awg.create_arb_sequence(app.awgDevice, sequenceHandle, app.waveformConfig);
            disp("ROI Imaging Module: Configured for Line Clock ROI Imaging");
        end


        function load_config(app,path)
            % read file
            fid = fopen(path, 'r');
            jsonStr = fscanf(fid, '%c');
            fclose(fid);
            % json to struct
            userConfig  = jsondecode(jsonStr);
            % assignment value
            app.defaultConfig = userConfig.defaultConfig;
            app.waveformConfig = userConfig.waveformConfig;
            app.scannerConfig = userConfig.scannerConfig;
        end

        function init_awg_settings(app)
            % defaultConfig
            app.defaultConfig.pulseOn = 0;
            app.defaultConfig.pulseOff = 1;
            app.defaultConfig.configPath = './config/';
            app.defaultConfig.roimaskPath = './roi_mask/';
            % waveformConfig
            app.waveformConfig.resourceID = "Dev1"; % AWG Var: resourceID
            app.waveformConfig.gain = 3.3;
            app.waveformConfig.offset = 0;
            app.waveformConfig.sampleRate = 1.0007e6; % 1.0007 MHz
            app.waveformConfig.delayTime = 0;
            app.waveformConfig.mode = 'Stepped'; % Stepped
            app.waveformConfig.clockSource = 'ClkIn'; % 'OnboardClock' or 'ClkIn'
            app.waveformConfig.triggerOn = true; % true or false
            app.waveformConfig.triggerPort = 'PFI0'; % PFI0 or PFI1
            app.waveformConfig.exportSignalOn = true; % true or false
            app.waveformConfig.exportSignalType = 'DataMarker'; % Marker DataMarker
            app.waveformConfig.exportSignalPort = 'PFI1'; % PFI0 or PFI1

            % scannerConfig
            app.scannerConfig.imageSize = 512;
            app.scannerConfig.pulsePerPixel = 1;
            app.scannerConfig.scanWait = 56;
            app.scannerConfig.scanBackLeftPixelTwice = 40; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            app.scannerConfig.scanBackRightPixelTwice = 40; % unit: pixel
            app.scannerConfig.clockMode = 'Line Clock'; % Line Clock or Frame Clock
        end

        function process_structure_image(app,filename,path)
            % read tiff stack
            fullpath = fullfile(path, filename);
            imgStack = utils.tiff_read(fullpath);
            % read reoulution info
            t = Tiff(fullpath, 'r');
            tagstruct = struct();
            try
                tagstruct.XResolution = t.getTag("XResolution");
                tagstruct.YResolution = tagstruct.XResolution;
            catch

            end
            t.close();


            if app.StructureTypeDropDown.Value == "1/10 Imaging"
                imgStack =  utils.tiff_extract(imgStack);
            end

            % get path
            folderProcessed = fullfile(path);
            if ~exist(folderProcessed, 'dir')
                mkdir(folderProcessed)
            end

            % 保存为raw tif
            frames = size(imgStack,3);
            [~, fname, fext] = fileparts(filename);


            if app.StructureTypeDropDown.Value == "1/10 Imaging"
                utils.tiff_save(imgStack,fullfile(folderProcessed, [fname,'_rebuild',fext]),tagstruct);
            end


            % AVG 和 imgStack 的数据类型不一样，AVG是8bit，所以app.seg\_img\_layer.CData换成imgStack就非常亮，可能需要把imgStack换成uint8类型？
            % imgStack_normalized = mat2gray(imgStack);
            % imgStack_normalized = imgStack_normalized * 255;
            %app.seg_img_stack = imgStack_normalized;
            app.seg_img_stack = imgStack;
            % 保存为average tif
            imgAvgCh = utils.tiff_projection_avg(imgStack);
            %utils.tiff_save(imgAvgCh,fullfile(folderProcessed, sprintf('%s_%d_Frames_AVG%s', fname,frames, fext)),tagstruct);

            % 自动调整对比度 EnhanceContrastsh
            %app.img_avg_Ch1 = imadjust(imgAvgCh);
            %utils.tiff_save(app.img_avg_Ch1,fullfile(folderProcessed, sprintf('%s_%d_Frames_AVG_EnhanceContrast%s', fname,frames, fext)),tagstruct);

            % 生成配准参考图
            % ops = register.default_ops();
            % app.refImg = register.compute_reference(imgStack,ops);
            % utils.tiff_save(app.refImg, ...
            %     fullfile(folderProcessed, sprintf('%s_ref%s', fname, fext)));

            % 加载Ch1的图像
            %app.ChannelDropDown.Enable = "on";

            hold(app.UIAxes,'off');
            app.img_seg_data = imgAvgCh;
            %app.ChannelDropDown.Value = 'CH1';
            app.seg_img_layer = imshow(app.img_seg_data,[],'parent',app.UIAxes,'border','tight','initialmagnification','fit');

            img_size = size(app.img_seg_data);
            axis(app.UIAxes,[0,img_size(2),0,img_size(1)]);
            hold(app.UIAxes,'on');

            % init DrawROI components
            app.init_DrawROI();

            % for save mask
            app.img_seg_fname = fname;
            app.img_seg_ext =  fext;
            app.img_seg_filename = [app.img_seg_fname,app.img_seg_ext];
            app.last_seg_tiff_folder = fullfile(path,'Structure');
            if ~exist(app.last_seg_tiff_folder, 'dir')
                mkdir(app.last_seg_tiff_folder)
            end
        end


        function caculate_power(app)
            % 计算理想激光功率，方便调整
            % get value
            imagingPower= app.ImagingPowerEditField.Value;
            powerCost = app.PowerCostEditField.Value;
            roiRatio = app.ROIRatioEditField.Value;
            scanArea = (app.scannerConfig.scanBackLeftPixelTwice/2+app.scannerConfig.imageSize+app.scannerConfig.scanBackRightPixelTwice/2)*app.scannerConfig.imageSize+app.scannerConfig.scanWait;
            acquisitionArea = app.scannerConfig.imageSize*app.scannerConfig.imageSize;
            fillfraction = acquisitionArea/scanArea;

            % caculate power
            %structurePower = imagingPower *0.1* powerCost*fillfraction;
            structurePower = imagingPower *0.1;
            roiPower = imagingPower*roiRatio*fillfraction*powerCost;

            % update value in gui
            app.StructurePowerEditField.Value = structurePower;
            app.ROIPowerEditField.Value = roiPower;
        end


        function structure_imaging_callback(app,~,~)
            % listen to scanimage event
            % reset device for new pusle
            % avoid awg device is not connected
            if isempty(app.awgDevice)
                uialert(app.UIFigure,"Please Connect AWG Device First",'Warning','Icon','warning','Modal',false);
                return
            end

            reset(app.awgDevice);

            % create structure pusle
            app.create_structure_pulse();
            disp("ROI Imaging Module: 0.1MHz Imaging");

            % 0.1MHZ: light lamp

            app.RegularImagingButton.FontWeight = 'normal';
            app.RegularImagingButton.FontColor = [0 0 0];
            app.Laser1on9offButton.FontWeight = 'bold';
            app.Laser1on9offButton.FontColor = [1 0 0];
            app.LaserROIImagingButton.FontWeight ='normal';
            app.LaserROIImagingButton.FontColor = [0 0 0];

            app.StructureImagingLamp.Color = [0.00,1.00,0.00];

        end

        function laser_keep_on(app)
            % avoid awg device is not connected
            if ~isvalid(app.awgDevice)
                uialert(app.UIFigure,"Please Connect AWG Device First",'Warning','Icon','warning','Modal',false);
                return
            end

            reset(app.awgDevice);

            % create Arb waveform
            waveformDataArray = repmat(app.defaultConfig.pulseOn,1,20);
            waveformHandle = awg.create_waveform_handle(app.awgDevice,waveformDataArray);

            % generate Arb waveform with no trigger(do not wait until scanimage grab a image)
            awg.create_arb_waveform_notrigger(app.awgDevice,waveformHandle,app.waveformConfig);

            % turn on nogating lamp
            app.RegularImagingButton.FontWeight = 'bold';
            app.RegularImagingButton.FontColor = [1 0 0];
            app.Laser1on9offButton.FontWeight = 'normal';
            app.Laser1on9offButton.FontColor = [0 0 0];
            app.LaserROIImagingButton.FontWeight ='normal';
            app.LaserROIImagingButton.FontColor = [0 0 0];

            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            pause(0.1);
            app.StructureImagingLamp.Color = [0.93,0.69,0.13];
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            disp("ROI Imaging Module: Laser Keep On");
        end


        function laser_keep_off(app)
            reset(app.awgDevice);
            % create pulse for keeping laser off
            waveformDataArray = repmat(app.defaultConfig.pulseOff,1,20);
            waveformHandle = awg.create_waveform_handle(app.awgDevice,waveformDataArray);

            % generate Arb waveform with no trigger(do not wait until scanimage grab a image)
            awg.create_arb_waveform_notrigger(app.awgDevice,waveformHandle,app.waveformConfig);

            % turn off all lamps
            app.StructureImagingLamp.Color = [0.90,0.90,0.90];
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];


            app.RegularImagingButton.FontWeight = 'normal';
            app.RegularImagingButton.FontColor = [0 0 0];
            app.Laser1on9offButton.FontWeight = 'normal';
            app.Laser1on9offButton.FontColor = [0 0 0];
            app.LaserROIImagingButton.FontWeight ='normal';
            app.LaserROIImagingButton.FontColor = [0 0 0];
        end

        function get_scanimage_power(app)
            lut = app.hSI.hBeams.hBeams{1, 1}.powerFraction2PowerWattLut;
            fraction = app.hSI.hBeams.powerFractions;
            power_W = utils.interp1_extended(lut(:,1),lut(:,2),fraction,'linear','extrap'); % unit: W
            app.ImagingPowerEditField.Value = round(power_W(1)*10^3);
        end

        function update_scanimage_power(app,~,~)
            % get scanimage power fraction and caculate the laser power by
            % lut
            app.get_scanimage_power();
            % caculate 0.1MHz and ROI actual power
            app.caculate_power();
        end

        function init_DrawROI(app)
            % create empty mask
            app.DrawROI = components.DrawROI(app, [app.UIAxes]);
            app.DrawROI.mask_size = size(app.img_seg_data);
            app.DrawROI.mask_color = 'random';
            app.DrawROI.showRoiNumber = false; % 默认不显示字体
            app.DrawROI.show_background = true; % 默认显示mask 背景
            app.DrawROI.roi_number_fontSize = 15; % 设置默认字体大小

            % enable components
            app.MaskOnCheckBox.Value = true;
            app.Seg.enable = true;
            app.Seg.auto_rerun = false;
            % save image data
            app.UIAxes.UserData.status = 'idle';
            app.UIAxes.UserData.origin_xlim = app.UIAxes.XLim;
            app.UIAxes.UserData.origin_ylim = app.UIAxes.YLim;
        end
        function roi_imaging_loop_callback(app,~,~)
            reset(app.awgDevice);


            % active low logic: 1（white) to 0, 0（black）to 1
            app.roiMask = utils.active_low_logic(app.DrawROI.binary_mask);

            % generate ROI pulse for each frame
            framePulse = app.create_frame_roi_pulse(app.roiMask);

            % create waveform
            waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

            % generate Arb waveform with no trigger(do not wait until scanimage grab a image)
            temp_config = app.waveformConfig;
            temp_config.mode = 'Continuous';
            awg.create_arb_waveform_notrigger(app.awgDevice,waveformHandle,temp_config);
        end

        function roi_imaging_callback(app,~,~)
            reset(app.awgDevice);

            % active low logic: 1（white) to 0, 0（black）to 1
            app.roiMask = utils.active_low_logic(app.DrawROI.binary_mask);

            % generate ROI pulse for each frame
            framePulse = app.create_frame_roi_pulse(app.roiMask);

            % create waveform
            waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

            % generate Arb waveform
            awg.create_arb_waveform(app.awgDevice,waveformHandle,app.waveformConfig);
        end

        function getCellposeModels(app, folderPath)
            % Get filenames in the specified folder
            files = dir(folderPath);

            % Initialize a set to store model names with an estimated maximum size
            maxFiles = length(files);
            modelNames = cell(1, maxFiles);
            modelCount = 0;

            % Iterate through each file in the folder
            for i = 1:maxFiles
                fileName = files(i).name;

                % Skip the current and parent directory entries
                if strcmp(fileName, '.') || strcmp(fileName, '..')
                    continue;
                end

                % Determine the model name based on the filename
                if startsWith(fileName, 'cyto2torch_')
                    modelName = 'cyto2';
                elseif startsWith(fileName, 'nucleitorch_')
                    modelName = 'nuclei';
                elseif startsWith(fileName, 'cytotorch_')
                    modelName = 'cyto';
                    
                else
                    modelName = fileName;
                end

                % Add the model name to the set if it's not already present
                if ~ismember(modelName, modelNames(1:modelCount))
                    modelCount = modelCount + 1;
                    modelNames{modelCount} = modelName;
                end
            end

            % Trim the modelNames array to the correct size
            modelNames = modelNames(1:modelCount);


            % Update the dropdown menu items and set the default value
            app.ModelsDropDown.Items = modelNames;
            app.ModelsDropDown.Value = modelNames{2};
        end

        
       function save_mask(app)
            try
                % save matfile
                data.roi_contours = app.DrawROI.roi_contours;
                data.original_roi_contours = app.DrawROI.original_roi_contours;
                data.dilate_level = app.DrawROI.dilate_level;
                data.roi_labeled_mask = uint16(app.DrawROI.generate_labeled_mask());
    
                save(fullfile(app.last_seg_tiff_folder,[app.img_seg_fname,'_roiMask.mat']), ...
                    "-struct", ...
                    "data");

                % save roi mask to imageJ
                utils.save_roiContour_to_imagej(app.DrawROI.roi_contours,fullfile(app.last_seg_tiff_folder,strcat(app.img_seg_fname,'_roiMask.zip')));

                app.UIAxes.XLim = app.UIAxes.UserData.origin_xlim;
                app.UIAxes.YLim = app.UIAxes.UserData.origin_ylim;
   
                exportgraphics(app.UIAxes, ...
                    fullfile(app.last_seg_tiff_folder, ...
                    [app.img_seg_fname,'_roiMask.pdf']), ...
                    'Resolution',600);
    
    
                exportgraphics(app.UIAxes, ...
                    fullfile(app.last_seg_tiff_folder, ...
                    [app.img_seg_fname,'_roiMask.png']), ...
                    'Resolution',600);
            catch ME
                % 捕获并显示错误信息
                errordlg(ME.message, 'Error');
                fprintf(2,'%s\n', ME.getReport('extended'));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % export app to base
            assignin("base",'app',app);

            % get app folder
            fullpath = mfilename('fullpath');
            [path,~]=fileparts(fullpath);
            app.folder = path;
            addpath(genpath(fullfile(app.folder,'libs')))

            % init awg settings
            app.init_awg_settings();


            % init DrawROI component
            app.DrawROI = components.DrawROI(app, [app.UIAxes]);
            app.UIFigure.UserData.CtrlPressed = false;
            app.UIFigure.UserData.ShiftPressed = false;
            app.UIFigure.UserData.AltPressed = false;
            app.UIFigure.UserData.SpacePressed = false;

            app.UIFigure.UserData.Pan = struct('previous_point', [0 0 0 0]);
            app.UIFigure.UserData.activeAxes = app.UIAxes; % 默认活动轴
            app.UIAxes.UserData.status = 'idle';
            app.UIAxes.UserData.origin_xlim = [];
            app.UIAxes.UserData.origin_ylim = [];
            app.UIFigure.UserData.mouseInAxes = false;
            app.UIFigure.UserData.forcePanMode = false;

            set(app.UIFigure, 'WindowButtonDownFcn', @app.windowButtonDown);
            set(app.UIFigure, 'WindowButtonMotionFcn', @app.windowMotion);
            set(app.UIFigure, 'WindowButtonUpFcn', @app.windowButtonUp);
            set(app.UIFigure, 'WindowKeyPressFcn', @app.keyPress);
            set(app.UIFigure, 'WindowKeyReleaseFcn', @app.keyRelease);
            set(app.UIFigure, 'WindowScrollWheelFcn', @app.windowScrollWheel);

            % init Seg component
            app.Seg = components.Segmentation();
            app.Seg.cellpose_model_folder = fullfile(app.folder,'cellposeModels/');
            getCellposeModels(app,app.Seg.cellpose_model_folder)

            % ui
            app.ScanimageButton.BackgroundColor = [1.00,0.00,0.00];

            % init config settings
            default_json = fullfile(app.folder,app.defaultConfig.configPath,'default.json');
            if exist(default_json,'file') ~= 0
                % if default.json exist
                app.load_config(default_json);
                app.ConfigurationFileEditField.Value = 'default.json';
            end


            % 确认Scanimage是否启动
            try
                app.hSI = evalin('base', 'hSI');
                app.hSICtl = evalin('base', 'hSICtl');
            catch
                uialert(app.UIFigure,"Please Start Scanimage First",'Warning','Icon','warning','Modal',false);
                return
            end
            app.ScanimageButton.BackgroundColor = [0.33,0.60,0.85];
            app.ScanimageButton.Value = true;


        end

        % Button pushed function: ConfigFileSelectButton
        function ConfigFileSelectButtonPushed(app, event)
            % select file
            if isempty(app.lastConfigPath)
                % 考虑当前文件夹可能不是app文件夹
                configPath = fullfile(app.folder,app.defaultConfig.configPath);
                [filename,path]= utils.select_file({'*.json'},configPath);
            else
                [filename,path]= utils.select_file({'*.json'}, app.lastConfigPath);
            end

            if filename ~= 0
                fullpath = [path,filename];

                % save path for next click
                app.lastConfigPath = path;

                % load conig
                app.load_config(fullpath)

                % show config file in GUI
                app.ConfigurationFileEditField.Value = filename;

                % log in command line
                disp(['Loaded Config：',fullpath]);


                % if subapp is open
                if ~isempty(app.AwgSettingsApp)
                    app.AwgSettingsApp.variableInit();
                end
                if ~isempty(app.ScannerSettingsApp)
                    app.ScannerSettingsApp.variableInit();
                end
            end


        end

        % Button pushed function: AdvancedSettingsOpenButton
        function AdvancedSettingsOpenButtonPushed(app, event)
            app.AwgSettingsApp = subapps.AwgSettings(app);
            app.ScannerSettingsApp = subapps.ScannerSettings(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % when exit MainApp
            % disconnect awg device
            if isvalid (app.StructureRebuilder)
                delete(app.StructureRebuilder);
            end
            if isvalid(app.structureListener)
                delete(app.structureListener);
            end

            if isvalid(app.awgDevice)
                reset(app.awgDevice);
                app.laser_keep_on();
                pause(2);
                awg.disconnect(app.awgDevice);
            end


            % close subapp
            if  ~isempty(app.AwgSettingsApp)
                delete(app.AwgSettingsApp)
            end
            if  ~isempty(app.ScannerSettingsApp)
                delete(app.ScannerSettingsApp)
            end
            if ~isempty(app.ROIMaskSettingsApp)
                delete(app.ROIMaskSettingsApp);
            end

            % close MainApp
            delete(app)
        end

        % Button pushed function: AwgConnectButton
        function AwgConnectButtonPushed(app, event)
            % If not connected, click to connect
            if app.isConnectedLabel.Text == "Disconnected"
                % simulated mode on or off
                simulateState = app.SimulationToggleTool.State;

                % create progress dialog
                d = uiprogressdlg(app.UIFigure,'Title','Connecting AWG',...
                    'Indeterminate','on');
                drawnow

                % connect to awg
                [app.awgDevice,status] = awg.connect(app.waveformConfig.resourceID,simulateState);

                % close the progress dialog
                close(d);

                if isvalid(app.awgDevice)
                    app.laser_keep_on();

                    % if connected to AWG already
                    if status
                        app.isConnectedLabel.Text = 'Connected';
                        app.AwgConnectButton.Text = 'Disconnect';
                    end
                end
            else
                % % If connected, click to disconnect
                reset(app.awgDevice);
                app.laser_keep_on();
                awg.disconnect(app.awgDevice);
                app.isConnectedLabel.Text = 'Disconnected';
                app.AwgConnectButton.Text = 'Connect';

                % turn off all lamps
                app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            end
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)

            app.laser_keep_off();

            app.StructureImagingLamp.Color = [0.90,0.90,0.90];

            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
        end

        % Button pushed function: LoadMaskButton
        function LoadMaskButtonPushed(app, event)
            app.LoadMaskButton.Enable = 'off';
            app.LoadMaskButton.FontColor = [1.00,1.00,1.00];
            app.LoadMaskButton.BackgroundColor = [0.96,0.65,0.11];
            % load mask
            if isempty(app.lastRoiMaskPath)
                % 考虑当前文件夹可能不是app文件夹
                [filename,path] = utils.select_file({ ...
                '*.mat;*.zip', 'ROI Mask files (*.mat, *.zip, *.png)'; ...
                '*.mat', 'MAT files (*.mat)'; ...
                '*.zip', 'Image ROI files (*.zip)'; ...
                '*.png;*.csv;*.txt;*.xlsx','Custom files (*.png;*.csv;*.txt;*.xlsx)'; ...
                '*.*', 'All Files (*.*)'}, ...
                app.last_seg_tiff_folder);

            else
                [filename,path] = utils.select_file({ ...
                    '*.mat;*.zip', 'ROI Mask files (*.mat, *.zip, *.png)'; ...
                    '*.mat', 'MAT files (*.mat)'; ...
                    '*.zip', 'Image ROI files (*.zip)'; ...
                    '*.png;*.csv;*.txt;*.xlsx','Custom files (*.png;*.csv;*.txt;*.xlsx)'; ...
                    '*.*', 'All Files (*.*)'}, ...
                    app.lastRoiMaskPath);
            end


            if filename ~= 0 % 如果不选择文件返回为0
                % save path for next click
                app.lastRoiMaskPath = path;

                % create progress dialog
                d = uiprogressdlg(app.UIFigure,'Title','Loading ROI Mask',...
                    'Indeterminate','on');
                drawnow

                % load roi
                
                try
                    app.DrawROI.load_roi_file(fullfile(path,filename));
                catch
                    app.LoadMaskButton.Enable = 'on';
                    app.LoadMaskButton.FontColor = [0,0,0];
                    app.LoadMaskButton.BackgroundColor = [0.96,0.96,0.96];
                    app.ROIImagingLamp.Color = [0.90,0.90,0.90];
                    return
                end
                
                % enable draw roi
                app.MaskOnCheckBox.Value = true;
                
                app.Seg.enable = true;
                app.Seg.auto_rerun = false;

                % reset roi dilate value
                %app.ROIdilateSpinner.Value = 0;

                % close the dialog box
                close(d);
            end

            %% update ui
            % enable load mask button
            app.LoadMaskButton.Enable = 'on';
            app.LoadMaskButton.FontColor = [0,0,0];
            app.LoadMaskButton.BackgroundColor = [0.96,0.96,0.96];

            % 关闭ROI imaging灯
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];

        end

        % Menu selected function: AWGSettingsMenu
        function AWGSettingsMenuSelected(app, event)
            app.AwgSettingsApp = subapps.AwgSettings(app);
        end

        % Menu selected function: ScannerSettingsMenu
        function ScannerSettingsMenuSelected(app, event)
            app.ScannerSettingsApp = subapps.ScannerSettings(app);
        end

        % Menu selected function: AWGControlMenu
        function AWGControlMenuSelected(app, event)
            app.AwgControlApp = addons.AWG_Control_2022b();

        end

        % Menu selected function: ROIImagingSimulationMenu
        function ROIImagingSimulationMenuSelected(app, event)
            app.SimulationApp = addons.simulation_veritical_stripe();
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)

        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)

            userConfig.defaultConfig = app.defaultConfig;
            userConfig.waveformConfig = app.waveformConfig;
            userConfig.scannerConfig = app.scannerConfig;


            if isempty(app.lastConfigPath)
                [filename,path] =  utils.save_file({'*.json'},app.defaultConfig.configPath);
            else
                [filename,path] =  utils.save_file({'*.json'}, app.lastConfigPath);
            end

            if ischar(filename) && ischar(path)
                fullpath = fullfile(path, filename);
                % save for next click
                app.lastConfigPath = path;
                % save to json
                jsonStr = jsonencode(userConfig,'PrettyPrint',true);
                fid = fopen(fullpath, 'w');
                fprintf(fid, jsonStr);
                fclose(fid);
                % log
                disp(['Config Saved to File: ',fullpath]);

                % change config file in GUI
                app.ConfigurationFileEditField.Value = filename;
            end

        end

        % Callback function
        function ChannelDropDownValueChanged(app, event)
            if isempty(app.img_avg_Ch1) || ~any(app.img_avg_Ch1,'all')
                return
            end

            value = app.ChannelDropDown.Value;
            switch value
                case 'CH1'
                    img = app.img_avg_Ch1;
                    app.img_seg_filename = [app.img_seg_fname,'_ch1',app.img_seg_ext];
                case 'CH2'
                    img = app.img_avg_Ch2;
                    app.img_seg_filename = [app.img_seg_fname,'_ch2',app.img_seg_ext];
            end

            hold(app.UIAxes,'off');
            app.img_seg_data = img;
            imshow(app.img_seg_data,[],'parent',app.UIAxes,'border','tight','initialmagnification','fit');
            hold(app.UIAxes,'on');

            % init DrawROI components
            app.init_DrawROI();
        end

        % Menu selected function: PowerCaculateMenu
        function PowerCaculateMenuSelected(app, event)
            app.PowerCaculateAPP = addons.Power_Caculate();
        end

        % Value changed function: ImagingPowerEditField
        function ImagingPowerEditFieldValueChanged(app, event)
            app.caculate_power();

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if isa(app.hSI,'scanimage.SI')
                app.get_scanimage_power();
                % 更新完功率后自动更新
                app.caculate_power();
            else
                uialert(app.UIFigure,"Please Start Scanimage First",'Warning','Icon','warning','Modal',false);
            end


        end

        % Value changed function: ScanimageButton
        function ScanimageButtonValueChanged(app, event)
            value = app.ScanimageButton.Value;
            if value
                try
                    app.hSI = evalin('base', 'hSI');
                    app.hSICtl = evalin('base', 'hSICtl');
                catch
                    app.ScanimageButton.Value = false;
                    uialert(app.UIFigure,"Please Start Scanimage First", ...
                        'Warning','Icon','warning','Modal',false);
                    return;
                end


                app.ScanimageButton.BackgroundColor = [0.33,0.60,0.85];

                %app.CaculateButtonPushed();

            else
                app.hSI = scanimage.SI.empty;
                app.hSICtl = scanimage.SIController.empty;
                app.ScanimageButton.BackgroundColor = [1.00,0.00,0.00];
            end
        end

        % Value changed function: ROIdilateSpinner
        function ROIdilateSpinnerValueChanged(app, event)
            value = app.ROIdilateSpinner.Value;
            app.DrawROI.dilate_roi(value);


            % caculate ROI Power
            app.caculate_power();

            % 关闭ROI灯，提示ROI成像与当前ROI mask不一致
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
        end

        % Button pushed function: LoadSegImageButton
        function LoadSegImageButtonPushed(app, event)
            % Disable load button
            app.LoadSegImageButton.Enable = 'off';
            app.LoadSegImageButton.FontColor = [1.00,1.00,1.00];
            app.LoadSegImageButton.BackgroundColor = [0.96,0.65,0.11];
            [filename,path] = utils.select_file({'*.tif';'*.png'},app.last_seg_tiff_folder);
            
            if filename ~= 0
                % create progress dialog
                d = uiprogressdlg(app.UIFigure,'Title','Loading Image',...
                    'Indeterminate','on');
                drawnow

                % save path for next click
                app.last_seg_tiff_folder = path;
                app.img_seg_filename = filename; % 获取文件名+后缀
                [~, app.img_seg_fname, ~]  = fileparts(app.img_seg_filename); % 获取文件名，无后缀

                info = imfinfo(fullfile(path,filename));

                % load image
                if length(info)>1
                    % stacked frames
                    app.DropDown.Enable = 'on';
                    process_structure_image(app,filename,path);
                else
                    % single frame
                    app.img_seg_data= imread(fullfile(app.last_seg_tiff_folder,filename));
                    app.refImg = app.img_seg_data;
                    %app.img_seg_data = imadjust(app.img_seg_data);

                    app.DropDown.Enable = 'off';
                    hold(app.UIAxes,'off');

                    app.seg_img_layer = imshow(app.img_seg_data,[],'parent',app.UIAxes,'border','tight','initialmagnification','fit');


                    img_size = size(app.img_seg_data);
                    axis(app.UIAxes,[0,img_size(2),0,img_size(1)]);
                    hold(app.UIAxes,'on');

                    % init DrawROI components
                    app.init_DrawROI();
                end

                % close the dialog box
                close(d);

            end

            % Enable load button
            app.LoadSegImageButton.Enable = 'on';
            app.LoadSegImageButton.FontColor = [0,0,0];
            app.LoadSegImageButton.BackgroundColor = [0.96,0.96,0.96];

            % 关闭ROI imaging灯
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            app.ROIsEditField.Value = 0;
            app.ROIRatioEditField.Value =0;

        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            if ~app.Seg.enable
                return
            end

            % process bar
            progressDlg = uiprogressdlg(app.UIFigure,'Title','Running neuron segmentation',...
                'Indeterminate','on');
            drawnow

            model_type = app.ModelsDropDown.Value;
            flow_threshold = app.ThresholdSpinner.Value;
            cp = cellpose(Model=model_type,ModelFolder=app.Seg.cellpose_model_folder);
            labeled_mask = segmentCells2D(cp,app.img_seg_data,CellThreshold=0,FlowErrorThreshold=flow_threshold,ImageCellDiameter=15); %ImageCellDiameter=56
            app.DrawROI.load_from_mask(labeled_mask);
            % close the dialog box
            close(progressDlg);


            app.Seg.auto_rerun = true; % 支持调整threshold，就自动显示
            app.ROIdilateSpinner.Value = 0;

            % 关闭ROI灯，提示ROI成像与当前ROI mask不一致
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];

        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)

        end

        % Value changed function: ModelsDropDown
        function ModelsDropDownValueChanged(app, event)

            app.Seg.auto_rerun = false;

        end

        % Value changed function: ROIMaskDropDown
        function ROIMaskDropDownValueChanged(app, event)
            
        end

        % Value changed function: MaskOnCheckBox
        function MaskOnCheckBoxValueChanged(app, event)

            if isempty(app.DrawROI)
                return
            end
            value = app.MaskOnCheckBox.Value;
            app.DrawROI.set_roi_visibility(1, value);

        end

        % Button pushed function: UIAxesHomeButton
        function UIAxesHomeButtonPushed(app, event)
            try
                app.UIAxes.XLim = app.UIAxes.UserData.origin_xlim;
                app.UIAxes.YLim = app.UIAxes.UserData.origin_ylim;
            catch
            end
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)

            [~, app.img_seg_fname, ~]  = fileparts(app.img_seg_filename);
            non_modal_filename_input(app,app.img_seg_fname);

            function non_modal_filename_input(app,file_name)
                % 创建一个非模态窗口
                figPos = app.UIFigure.Position;
                figWidth = 300;
                figHeight = 200;
                figLeft = figPos(1) + (figPos(3) - figWidth)/2;
                figTop = figPos(2) + (figPos(4) - figHeight)/2;


                hFig = figure('Name', 'Enter File Name', ...
                    'NumberTitle', 'off', ...
                    'MenuBar', 'none', ...
                    'ToolBar', 'none', ...
                    'Resize', 'on', ...
                    'Position', [figLeft, figTop, figWidth, figHeight], ...
                    'WindowStyle', 'normal');
                % 创建一个文本框用于用户输入文件名
                hEdit = uicontrol('Style', 'edit', ...
                    'String',file_name,...
                    'Parent', hFig, ...
                    'Units', 'normalized', ...
                    'Position', [0.1, 0.5, 0.8, 0.1]);

                % 创建一个按钮，用户点击后保存文件名
                hButton = uicontrol('Style', 'pushbutton', ...
                    'Parent', hFig, ...
                    'Units', 'normalized', ...
                    'Position', [0.1, 0.3, 0.8, 0.1], ...
                    'String', 'Save', ...
                    'Callback', {@saveFileNameCallback, hFig,hEdit,app});
            end

            function saveFileNameCallback(~, ~, hFig,hEdit,app)
                % 获取用户输入的文件名
                fileName = get(hEdit, 'String');
                app.img_seg_fname = fileName;
                % 在这里执行保存文件的操作
                close(hFig);
                d = uiprogressdlg(app.UIFigure,'Title','Saving',...
                'Indeterminate','on');
                % save ROI
                try
                    save_mask(app);
                catch
                     close(d);
                     return
                end
                close(d);
                % hint: done
                uialert(app.UIFigure,'Save Mask Done','Done','Icon','success','Modal',false);
            end
        end

        % Button pushed function: LaserROIImagingButton
        function LaserROIImagingButtonPushed(app, event)
            % avoid awg device is not connected
            if isempty(app.awgDevice) || ~isvalid(app.awgDevice) % Check validity too
                uialert(app.UIFigure,"Please Connect AWG Device First",'Warning','Icon','warning','Modal',false);
                return
            end

            % 自动保存ROI mask
            if ~isempty(app.img_seg_filename)
                % Check if DrawROI object and properties exist before saving
                if isvalid(app.DrawROI) 
                    save_mask(app);
                else
                    warning('DrawROI object or its properties are not valid. Skipping mask save.');
                end
            else
                warning('Image segment filename is empty. Skipping mask save.');
            end
            % delete 0.1MHz listener and Rebuild
            if isvalid (app.StructureRebuilder)
                delete(app.StructureRebuilder);
            end

            if isvalid(app.structureListener)
                delete(app.structureListener);
            end

            %% set AWG output
            % reset AWG device for new waveform
            app.laser_keep_off();
            reset(app.awgDevice);
            

            % Check clock mode and configure AWG accordingly
            if strcmpi(app.scannerConfig.clockMode, 'Line Clock')
                % --- Line Clock Logic ---
                awg_output_roi_lines_pulse(app);

            elseif strcmpi(app.scannerConfig.clockMode, 'Frame Clock')
                % --- Frame Clock Logic (Original) ---
                % Determine ROI mask based on pulse logic
                if ~app.defaultConfig.pulseOn
                    app.roiMask = utils.active_low_logic(app.DrawROI.binary_mask);
                else
                    app.roiMask = app.DrawROI.binary_mask;
                end

                % generate ROI pulse for the entire frame
                framePulse = app.create_frame_roi_pulse(app.roiMask);

                % create waveform handle
                waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

                % generate Arb waveform (triggered by frame clock)
                awg.create_arb_waveform(app.awgDevice,waveformHandle,app.waveformConfig);
                disp("ROI Imaging Module: Configured for Frame Clock ROI Imaging");
            else
                uialert(app.UIFigure,['Unknown clock mode: ', app.scannerConfig.clockMode],'Error','Icon','error','Modal',false);
                return;
            end
            %% light lamp
            app.RegularImagingButton.FontWeight = 'normal';
            app.RegularImagingButton.FontColor = [0 0 0];
            app.Laser1on9offButton.FontWeight = 'normal';
            app.Laser1on9offButton.FontColor = [0 0 0];
            app.LaserROIImagingButton.FontWeight ='bold';
            app.LaserROIImagingButton.FontColor = [1 0 0];

            app.StructureImagingLamp.Color = [0.90,0.90,0.90];

            % 闪一下
            app.ROIImagingLamp.Color = [0.90,0.90,0.90];
            pause(0.1);
            app.ROIImagingLamp.Color = [0.07,0.62,1.00];
            disp("ROI Imaging Module: ROI Imaging");
        end

        % Button pushed function: RegularImagingButton
        function RegularImagingButtonPushed(app, event)
            app.laser_keep_on();

            % Delete 0.1 MHz Listener to SCANIMAGE for Laser Keep On!
            if isvalid (app.StructureRebuilder)
                delete(app.StructureRebuilder);
            end

            if isvalid(app.structureListener)
                delete(app.structureListener);
            end
        end

        % Button pushed function: Laser1on9offButton
        function Laser1on9offButtonPushed(app, event)
            if isempty(app.hSI)
                uialert(app.UIFigure,"Please Connect Scanimage First",'Warning','Icon','warning','Modal',false);
                return
            end

            % 控制AWG进行十分之一成像
            app.structure_imaging_callback();

            % 实时成像重建：根据scanimage当前打开的channel进行重建
            delete(app.StructureRebuilder);
            app.StructureRebuilder= components.ScanimageRealtimeRebuildAvg(app.hSI);
            app.StructureRebuilder.listen_to_scanimage();

            % 0.1MHZ 监听程序
            if isa(app.hSI,'scanimage.SI')
                if isvalid(app.structureListener)
                    delete(app.structureListener);
                end
                %TODO：目前这个貌似没有意义了，因为不需要第一帧就是最左边开始拍
                app.structureListener = addlistener(app.hSI.hUserFunctions, 'acqModeStart', @app.structure_imaging_callback); % focus或grab结束后，自动重置结构成像
                disp("ROI Imaging Module: Listener to SCANIMAGE for 0.1MHz");
            else
                warning("Please Start Scanimage First");
            end

        end

        % Value changed function: ChannelDropDown_2
        function ChannelDropDown_2ValueChanged(app, event)
            value = app.ChannelDropDown_2.Value;
            disp(value);
        end

        % Button pushed function: RealtimeregistrationButton
        function RealtimeregistrationButtonPushed(app, event)
            % 备注：必须要focus才能正常发送！
            % 获取当前成像的roiData
            stripeData = app.hSI.hDisplay.lastStripeData;
            roiData = stripeData.roiData{1};

            channels = roiData.channels;
            channel = channels(app.ChannelDropDown_2.Value);
            z = roiData.zs(1); % use the first available z
            roiData.onlyKeepZs(z);
            roiData.onlyKeepChannels(channel);

            img = app.refImg;
            % roiData的imageDate替换为指定的图像，图像需要先进行根据lut，避免看不到图像
            lut = single(app.hSI.hChannels.channelLUT{channel});
            black = lut(1);
            white = lut(2);
            img = rescale(single(img), black, white);
            roiData.imageData{1}{1} = img';

            % 发送给scanimage
            app.hSI.hMotionManager.clearEstimators();
            app.hSI.hMotionManager.addEstimator(roiData);
            app.hSICtl = app.hSI.hController{1};

            % 显示MOtion correction界面
            hGUI = app.hSICtl.hGuiClasses.MotionDisplay;
            app.hSICtl.showGUI('MotionDisplay');
            app.hSICtl.raiseGUI('MotionDisplay');
            hGUI.currentZ = z;
            hGUI.selectedEstimator = app.hSI.hMotionManager.hMotionEstimators(1);

            app.hSI.hMotionManager.enable = true;
        end

        % Value changed function: AdjustButton
        function AdjustButtonValueChanged(app, event)
            value = app.AdjustButton.Value;
            if value
                img_adjusted = imadjust(app.img_seg_data);
                app.seg_img_layer.CData = img_adjusted;
            else
                app.seg_img_layer.CData = app.img_seg_data;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'AVG'
                    if ~isempty(app.img_seg_data)
                        % 隐藏滑条
                        app.FrameSlider.Visible = 'off';
                        app.FrameSliderLabel.Visible = 'off';
                        app.seg_img_layer.CData = app.img_seg_data;
                    end
                case 'Movie'
                    if ~isempty(app.img_seg_data)
                        % 显示滑条
                        app.FrameSlider.Visible = 'on';
                        app.FrameSliderLabel.Visible = 'on';
                        app.seg_img_layer.CData = app.seg_img_stack(:,:,1);
                        n_frames = size(app.seg_img_stack,3);
                        app.FrameSlider.Value =1;
                        app.FrameSlider.Limits = [1,n_frames];
                        app.FrameSliderLabel.Text = sprintf("%d/%d",1,n_frames);
                    end


            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            n_frames = size(app.seg_img_stack,3);
            current_frame = round(value);
            app.FrameSliderLabel.Text = sprintf("%d/%d",current_frame,n_frames);
            app.seg_img_layer.CData = app.seg_img_stack(:,:,current_frame);
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            n_frames = size(app.seg_img_stack,3);
            current_frame = round(changingValue);
            app.FrameSliderLabel.Text = sprintf("%d/%d",current_frame,n_frames);
            app.seg_img_layer.CData = app.seg_img_stack(:,:,current_frame);
        end

        % Size changed function: ManualcorrectionPanel
        function ManualcorrectionPanelSizeChanged(app, event)
            position = app.ManualcorrectionPanel.Position;

        end

        % Callback function
        function editButtonPushed(app, event)

        end

        % Callback function
        function editButtonValueChanged(app, event)

            if ~app.DrawROI.enable
                app.editButton.Value = false;
                return
            end

            value = app.editButton.Value;
            if ~value
                app.DrawROI.modifyROI();
                app.editButton.Text = "edit";
            else
                app.DrawROI.modifyROI();
                app.editButton.Text = "done";
            end
        end

        % Button pushed function: ROIMaskClearButton
        function ROIMaskClearButtonPushed(app, event)
            if ~isempty(app.DrawROI)
                choice = questdlg('Are you sure you want to clear all ROIs?', ...
                    'Clear ROIs', 'Yes', 'No', 'No');
                if strcmp(choice, 'Yes')
                    app.DrawROI.clear_all_rois();
                end
            end
        end

        % Button pushed function: ROIMaskSettingsButton
        function ROIMaskSettingsButtonPushed(app, event)
            % ROI面板打开ROIMaskSettings
            app.ROIMaskSettingsApp=subapps.ROIMaskSettings(app);
        end

        % Menu selected function: ROIMaskSettingsMenu
        function ROIMaskSettingsMenuSelected(app, event)
            % 顶部菜单打开ROIMaskSettings
            app.ROIMaskSettingsApp=subapps.ROIMaskSettings(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {670, 670};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ROI Imaging Module';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = ' File ';
            app.LoadStructureImageMenu = uimenu(app.FileMenu);
            app.LoadStructureImageMenu.Text = 'Load Structure Image';
            app.LoadExternalmaskMenu = uimenu(app.FileMenu);
            app.LoadExternalmaskMenu.Text = 'Load External mask';
            app.SaveConfigMenu = uimenu(app.FileMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Config';
            app.LoadConfigMenu = uimenu(app.FileMenu);
            app.LoadConfigMenu.Text = 'Load Config';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = ' Settings ';
            app.AWGSettingsMenu = uimenu(app.SettingsMenu);
            app.AWGSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AWGSettingsMenuSelected, true);
            app.AWGSettingsMenu.Text = 'AWG Settings';
            app.ScannerSettingsMenu = uimenu(app.SettingsMenu);
            app.ScannerSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ScannerSettingsMenuSelected, true);
            app.ScannerSettingsMenu.Text = 'Scanner Settings';
            app.ROIMaskSettingsMenu = uimenu(app.SettingsMenu);
            app.ROIMaskSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIMaskSettingsMenuSelected, true);
            app.ROIMaskSettingsMenu.Text = 'ROI Mask Settings';
            app.AddonsMenu = uimenu(app.UIFigure);
            app.AddonsMenu.Text = ' Add-ons ';
            app.PowerCaculateMenu = uimenu(app.AddonsMenu);
            app.PowerCaculateMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerCaculateMenuSelected, true);
            app.PowerCaculateMenu.Text = 'Power Caculate';
            app.AWGControlMenu = uimenu(app.AddonsMenu);
            app.AWGControlMenu.MenuSelectedFcn = createCallbackFcn(app, @AWGControlMenuSelected, true);
            app.AWGControlMenu.Text = 'AWG Control';
            app.ROIImagingSimulationMenu = uimenu(app.AddonsMenu);
            app.ROIImagingSimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIImagingSimulationMenuSelected, true);
            app.ROIImagingSimulationMenu.Text = 'ROI Imaging Simulation';
            app.CustomDrawMenu_2 = uimenu(app.AddonsMenu);
            app.CustomDrawMenu_2.Text = 'Custom Draw';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = ' Help ';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SimulationToggleTool = uitoggletool(app.Toolbar);
            app.SimulationToggleTool.Icon = fullfile(pathToMLAPP, '+assets', 'simulation.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NeuronSegmentationPanel = uipanel(app.LeftPanel);
            app.NeuronSegmentationPanel.Title = '2. Neuron Segmentation';
            app.ModelsDropDownLabel = uilabel(app.NeuronSegmentationPanel);
            app.ModelsDropDownLabel.Text = 'Models';
            app.ModelsDropDown = uidropdown(app.NeuronSegmentationPanel);
            app.ModelsDropDown.Items = {'cyto3', 'cyto2', 'cyto'};
            app.ModelsDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelsDropDownValueChanged, true);
            app.ModelsDropDown.Tooltip = {'segmentation model'};
            app.ModelsDropDown.Value = 'cyto3';
            app.ThresholdSpinnerLabel = uilabel(app.NeuronSegmentationPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.NeuronSegmentationPanel);
            app.ThresholdSpinner.Step = 0.05;
            app.ThresholdSpinner.LowerLimitInclusive = 'off';
            app.ThresholdSpinner.Limits = [0 3];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Tooltip = {'set  higher to get more cells, in range from (0,3]'};
            app.ThresholdSpinner.Value = 0.4;
            app.RunModelButton = uibutton(app.NeuronSegmentationPanel, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.LoadSegImageButton = uibutton(app.NeuronSegmentationPanel, 'push');
            app.LoadSegImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegImageButtonPushed, true);
            app.LoadSegImageButton.Icon = fullfile(pathToMLAPP, '+assets', 'folder-open.svg');
            app.LoadSegImageButton.Tooltip = {'Load Image to Segmentation'};
            app.LoadSegImageButton.Text = 'Load Structure Image';
            app.StructureTypeDropDown = uidropdown(app.NeuronSegmentationPanel);
            app.StructureTypeDropDown.Items = {'normal imaging', '1/10 Imaging'};
            app.StructureTypeDropDown.Value = 'normal imaging';
            app.ManualcorrectionPanel = uipanel(app.LeftPanel);
            app.ManualcorrectionPanel.Title = '3. Manual correction';
            app.ManualcorrectionPanel.SizeChangedFcn = createCallbackFcn(app, @ManualcorrectionPanelSizeChanged, true);
            app.ROIMaskDropDownLabel = uilabel(app.ManualcorrectionPanel);
            app.ROIMaskDropDownLabel.Text = 'ROI Mask';
            app.ROIMaskDropDown = uidropdown(app.ManualcorrectionPanel);
            app.ROIMaskDropDown.Items = {'Colored', 'Binary'};
            app.ROIMaskDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIMaskDropDownValueChanged, true);
            app.ROIMaskDropDown.Tooltip = {'Choose mask style to display: colored mask or binary mask'};
            app.ROIMaskDropDown.Value = 'Colored';
            app.MaskOnCheckBox = uicheckbox(app.ManualcorrectionPanel);
            app.MaskOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaskOnCheckBoxValueChanged, true);
            app.MaskOnCheckBox.Text = '';
            app.ROIdilateSpinnerLabel = uilabel(app.ManualcorrectionPanel);
            app.ROIdilateSpinnerLabel.Text = 'ROI  dilate';
            app.ROIdilateSpinner = uispinner(app.ManualcorrectionPanel);
            app.ROIdilateSpinner.Limits = [0 Inf];
            app.ROIdilateSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIdilateSpinnerValueChanged, true);
            app.ROIdilateSpinner.Tooltip = {'Dilate roi mask, in range from [0,+∞}'};
            app.LoadMaskButton = uibutton(app.ManualcorrectionPanel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonPushed, true);
            app.LoadMaskButton.Icon = fullfile(pathToMLAPP, '+assets', 'upload.svg');
            app.LoadMaskButton.Tooltip = {'Load external mask  '};
            app.LoadMaskButton.Text = 'Load Mask';
            app.SaveMaskButton = uibutton(app.ManualcorrectionPanel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Icon = fullfile(pathToMLAPP, '+assets', 'save.svg');
            app.SaveMaskButton.Tooltip = {'choose where to save mask as .mat and .jpg'};
            app.SaveMaskButton.Text = 'Save Mask';
            app.ROIMaskClearButton = uibutton(app.ManualcorrectionPanel, 'push');
            app.ROIMaskClearButton.ButtonPushedFcn = createCallbackFcn(app, @ROIMaskClearButtonPushed, true);
            app.ROIMaskClearButton.Icon = fullfile(pathToMLAPP, '+assets', 'clear.svg');
            app.ROIMaskClearButton.Tooltip = {'Clear all ROI'};
            app.ROIMaskClearButton.Text = '';
            app.ROIMaskSettingsButton = uibutton(app.ManualcorrectionPanel, 'push');
            app.ROIMaskSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ROIMaskSettingsButtonPushed, true);
            app.ROIMaskSettingsButton.Icon = fullfile(pathToMLAPP, '+assets', 'setting.svg');
            app.ROIMaskSettingsButton.Tooltip = {'Open Settings'};
            app.ROIMaskSettingsButton.Text = '';
            app.ROIImagingPanel = uipanel(app.LeftPanel);
            app.ROIImagingPanel.Title = '4. ROI Imaging';
            app.LaserROIImagingButton = uibutton(app.ROIImagingPanel, 'push');
            app.LaserROIImagingButton.ButtonPushedFcn = createCallbackFcn(app, @LaserROIImagingButtonPushed, true);
            app.LaserROIImagingButton.Text = 'ROI Imaging';
            app.AbortButton = uibutton(app.ROIImagingPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.ROIImagingLamp = uilamp(app.ROIImagingPanel);
            app.ChannelDropDown_2 = uidropdown(app.ROIImagingPanel);
            app.ChannelDropDown_2.Items = {'CH1', 'CH2'};
            app.ChannelDropDown_2.ItemsData = [1 2];
            app.ChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDown_2ValueChanged, true);
            app.ChannelDropDown_2.Value = 1;
            app.RealtimeregistrationButton = uibutton(app.ROIImagingPanel, 'push');
            app.RealtimeregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RealtimeregistrationButtonPushed, true);
            app.RealtimeregistrationButton.Text = 'Real-time registration';
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.AwgConnectButton = uibutton(app.SettingsPanel, 'push');
            app.AwgConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AwgConnectButtonPushed, true);
            app.AwgConnectButton.Text = 'Connect';
            app.AWGStatusLabel = uilabel(app.SettingsPanel);
            app.AWGStatusLabel.Text = 'AWG Status';
            app.isConnectedLabel = uilabel(app.SettingsPanel);
            app.isConnectedLabel.Text = 'Disconnected';
            app.ConfigFileSelectButton = uibutton(app.SettingsPanel, 'push');
            app.ConfigFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigFileSelectButtonPushed, true);
            app.ConfigFileSelectButton.Text = '...';
            app.AdvancedSettingsOpenButton = uibutton(app.SettingsPanel, 'push');
            app.AdvancedSettingsOpenButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSettingsOpenButtonPushed, true);
            app.AdvancedSettingsOpenButton.Text = 'Advanced';
            app.ConfigurationEditFieldLabel = uilabel(app.SettingsPanel);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationFileEditField = uieditfield(app.SettingsPanel, 'text');
            app.ScanimageButton = uibutton(app.SettingsPanel, 'state');
            app.ScanimageButton.ValueChangedFcn = createCallbackFcn(app, @ScanimageButtonValueChanged, true);
            app.ScanimageButton.Icon = fullfile(pathToMLAPP, '+assets', 'ScanImage.png');
            app.ScanimageButton.Text = '';
            app.StructureImagingPanel = uipanel(app.LeftPanel);
            app.StructureImagingPanel.Title = '1. Structure Imaging';
            app.StructureImagingLamp = uilamp(app.StructureImagingPanel);
            app.Laser1on9offButton = uibutton(app.StructureImagingPanel, 'push');
            app.Laser1on9offButton.ButtonPushedFcn = createCallbackFcn(app, @Laser1on9offButtonPushed, true);
            app.Laser1on9offButton.Text = '1/10 ROI Imaging';
            app.RegularImagingButton = uibutton(app.StructureImagingPanel, 'push');
            app.RegularImagingButton.ButtonPushedFcn = createCallbackFcn(app, @RegularImagingButtonPushed, true);
            app.RegularImagingButton.Text = 'Regular Imaging';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PowerCaculatePanel = uipanel(app.RightPanel);
            app.PowerCaculatePanel.Title = 'Power Caculate';
            app.LaserPowermWLabel = uilabel(app.PowerCaculatePanel);
            app.LaserPowermWLabel.Text = 'Laser  Power(mW)';
            app.ImagingPowerEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.ImagingPowerEditField.ValueChangedFcn = createCallbackFcn(app, @ImagingPowerEditFieldValueChanged, true);
            app.MHzPowermWEditFieldLabel = uilabel(app.PowerCaculatePanel);
            app.MHzPowermWEditFieldLabel.Text = '0.1MHz Power(mW)';
            app.StructurePowerEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.StructurePowerEditField.Editable = 'off';
            app.ROIPowermWEditField_2Label = uilabel(app.PowerCaculatePanel);
            app.ROIPowermWEditField_2Label.Text = 'ROI Power(mW)';
            app.ROIPowerEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.ROIPowerEditField.Editable = 'off';
            app.PowerCoseLabel = uilabel(app.PowerCaculatePanel);
            app.PowerCoseLabel.Text = 'Power Cost';
            app.PowerCostEditField = uieditfield(app.PowerCaculatePanel, 'numeric');
            app.PowerCostEditField.Value = 1;
            app.UpdateButton = uibutton(app.PowerCaculatePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ROIsEditFieldLabel = uilabel(app.RightPanel);
            app.ROIsEditFieldLabel.Text = 'ROIs';
            app.ROIsEditField = uieditfield(app.RightPanel, 'numeric');
            app.ROIsEditField.Limits = [0 Inf];
            app.ROIsEditField.ValueDisplayFormat = '%.0f';
            app.ROIsEditField.Editable = 'off';
            app.ROIRatioEditField_2Label = uilabel(app.RightPanel);
            app.ROIRatioEditField_2Label.Text = 'ROI Ratio';
            app.ROIRatioEditField = uieditfield(app.RightPanel, 'numeric');
            app.ROIRatioEditField.Limits = [0 Inf];
            app.ROIRatioEditField.ValueDisplayFormat = '%.3f';
            app.ROIRatioEditField.Editable = 'off';
            app.UIAxesHomeButton = uibutton(app.RightPanel, 'push');
            app.UIAxesHomeButton.ButtonPushedFcn = createCallbackFcn(app, @UIAxesHomeButtonPushed, true);
            app.UIAxesHomeButton.Icon = fullfile(pathToMLAPP, '+assets', 'home.svg');
            app.UIAxesHomeButton.Text = '';
            app.AdjustButton = uibutton(app.RightPanel, 'state');
            app.AdjustButton.ValueChangedFcn = createCallbackFcn(app, @AdjustButtonValueChanged, true);
            app.AdjustButton.Text = 'Adjust';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {'AVG', 'Movie'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'AVG';
            app.Label = uilabel(app.RightPanel);
            app.ContrastLabel = uilabel(app.RightPanel);
            app.ContrastLabel.Text = 'Contrast';
            app.FrameSlider = uislider(app.RightPanel);
            app.FrameSlider.Limits = [1 1000];
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Visible = 'off';
            app.FrameSlider.Value = 1;
            app.FrameSliderLabel = uilabel(app.RightPanel);
            app.FrameSliderLabel.Visible = 'off';
            app.FrameSliderLabel.Text = '1/1000';
            app.ShowROINumbersCheckBox = uicheckbox(app.RightPanel);
            app.ShowROINumbersCheckBox.Tooltip = {'Show ROI number'};
            app.ShowROINumbersCheckBox.Text = '';
            app.ShowROINumbersCheckBox.Value = true;
            app.ROIIDLabel = uilabel(app.RightPanel);
            app.ROIIDLabel.Text = 'ROI ID ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roi_imaging_module

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end