classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ListenerButton  matlab.ui.control.Button
        NotifyButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        listeners
    end
    events
        sendmsg; % Description
    end

    
    methods (Access = private)
        function callback(~,~,~)
            disp("listener callback");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ListenerButton
        function ListenerButtonPushed(app, event)
            app.listeners{1} = app.addlistener('sendmsg', @app.callback);
            disp("listening");
        end

        % Button pushed function: NotifyButton
        function NotifyButtonPushed(app, event)
            disp("notified");
            app.notify('sendmsg');
            disp("after Notify code");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            isempty(app.listeners)
            if ~isempty(app.listeners)
                delete(app.listeners{1});
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NotifyButton = uibutton(app.UIFigure, 'push');
            app.NotifyButton.ButtonPushedFcn = createCallbackFcn(app, @NotifyButtonPushed, true);
            app.NotifyButton.Text = 'Notify Button';
            app.ListenerButton = uibutton(app.UIFigure, 'push');
            app.ListenerButton.ButtonPushedFcn = createCallbackFcn(app, @ListenerButtonPushed, true);
            app.ListenerButton.Text = 'Listener Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end